classdef dockAnnotation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        btnOK                matlab.ui.control.Button
        attributeValue       matlab.ui.control.TextArea
        attributeValueLabel  matlab.ui.control.Label
        attributeName        matlab.ui.control.DropDown
        attributeNameLabel   matlab.ui.control.Label
        homologationInfo     matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
        projectData
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        inputArgs
    end
    
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function updateForm(app, focusedHomologation)
            homMask = strcmp(app.mainApp.schDataTable.("Homologação"), focusedHomologation);
            annotationHomMask = strcmp(app.mainApp.annotationTable.("Homologação"), focusedHomologation);

            relatedSCHTable = app.mainApp.schDataTable(homMask, :);
            relatedAnnotationTable = app.mainApp.annotationTable(annotationHomMask, :);

            set( ...
                app.homologationInfo, ...
                'Text', util.HtmlTextGenerator.ProductInfoUnderAnnotation(relatedSCHTable, relatedAnnotationTable), ...
                'Tag', focusedHomologation ...
            )

            app.attributeName.Value  = '';
            app.attributeValue.Value = '';
            app.btnOK.Enable = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context, focusedHomologation)
            
            try
                appEngine.boot(app, app.Role, mainApp, callingApp)

                app.inputArgs = struct('context', context, 'focusedHomologation', focusedHomologation);
                updateForm(app, focusedHomologation)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end            
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            delete(app)
            
        end

        % Value changing function: attributeValue
        function attributeValueChanged(app, event)

            currentValue = textFormatGUI.cellstr2TextField(event.Value);
            btnOKEnable  = ~isempty(currentValue);

            if app.btnOK.Enable ~= btnOKEnable
                app.btnOK.Enable = btnOKEnable;
            end

        end

        % Button pushed function: btnOK
        function btnOKPushed(app, event)
           
            currentValue = textFormatGUI.cellstr2TextField(app.attributeValue.Value);

            if ~isempty(currentValue)
                focusedHomologation = app.homologationInfo.Tag;
                currentAttribute = app.attributeName.Value;

                ipcMainMatlabCallsHandler(app.mainApp, app, 'onProductAnnotationAdded', focusedHomologation, currentAttribute, currentValue)
                updateForm(app, focusedHomologation)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.homologationInfo = uilabel(app.GridLayout);
            app.homologationInfo.Interpreter = 'html';
            app.homologationInfo.Text = '';
            app.attributeNameLabel = uilabel(app.GridLayout);
            app.attributeNameLabel.Text = 'Atributo:';
            app.attributeName = uidropdown(app.GridLayout);
            app.attributeName.Items = {'', 'Fornecedor', 'Fabricante', 'Modelo', 'EAN', 'Outras informações'};
            app.attributeName.Value = '';
            app.attributeValueLabel = uilabel(app.GridLayout);
            app.attributeValueLabel.Text = 'Valor:';
            app.attributeValue = uitextarea(app.GridLayout);
            app.attributeValue.ValueChangingFcn = createCallbackFcn(app, @attributeValueChanged, true);
            app.btnOK = uibutton(app.GridLayout, 'push');
            app.btnOK.ButtonPushedFcn = createCallbackFcn(app, @btnOKPushed, true);
            app.btnOK.Tag = 'OK';
            app.btnOK.Enable = 'off';
            app.btnOK.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockAnnotation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end