classdef dockCustomsFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        ColumnFilterPanel       matlab.ui.container.Panel
        ColumnFilterGrid        matlab.ui.container.GridLayout
        ColumnFilterList        matlab.ui.container.CheckBoxTree
        ColumnFilterAdd         matlab.ui.control.Image
        Value2_TextFree         matlab.ui.control.EditField
        Value2_TextList         matlab.ui.control.DropDown
        Value2_Numeric          matlab.ui.control.NumericEditField
        Value2_Date             matlab.ui.control.DatePicker
        Operation2_List         matlab.ui.control.DropDown
        Operation2_LogicalGrid  matlab.ui.container.ButtonGroup
        Operation2_LogicalOr    matlab.ui.control.RadioButton
        Operation2_LogicalAnd   matlab.ui.control.RadioButton
        Value1_TextFree         matlab.ui.control.EditField
        Value1_TextList         matlab.ui.control.DropDown
        Value1_Numeric          matlab.ui.control.NumericEditField
        Value1_Date             matlab.ui.control.DatePicker
        Operation1_List         matlab.ui.control.DropDown
        SymbolicNameList        matlab.ui.control.DropDown
        ColumnFilterLabel       matlab.ui.control.Label
        Title                   matlab.ui.control.Label
        ContextMenu             matlab.ui.container.ContextMenu
        columnFilterDel         matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        inputArgs
    end
    
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function initialLayout(app)
            % DROPDOWN "COLUNAS"
            columnRawNames = app.mainApp.schData.detailed.Properties.VariableNames;
            columnRawTypes = matlab.Compatibility.resolveTableVariableTypes(app.mainApp.schData.detailed);

            filterableMask = ~startsWith(columnRawNames, '_');
            columnRawNames = columnRawNames(filterableMask);
            columnRawTypes = columnRawTypes(filterableMask);

            [columnNames, sortedIdxs] = textAnalysis.sort(columnRawNames);
            columnTypes = columnRawTypes(sortedIdxs);
            
            pseudoClasses = tableFiltering.getPseudoClasses(columnTypes);
            symbolicNames = tableFiltering.mergedSymbolWithColumnNames(columnNames, columnTypes);
            
            % Atualiza componente dropdown com os nomes símbolicos das
            % colunas, além de armazenar em "UserData" detalhes sobre os
            % tipos de dados (nome, tipo e pseudo classe de cada coluna).
            app.symbolicNameList.Items = [{''}; symbolicNames'];
            
            restartState(app)            
            app.symbolicNameList.UserData.columnNames   = columnNames;
            app.symbolicNameList.UserData.columnTypes   = columnTypes;
            app.symbolicNameList.UserData.pseudoClasses = pseudoClasses;
        end

        %-----------------------------------------------------------------%
        function restartState(app)
            % O componente de INPUT do valor do filtro pode ser uidatepicker,
            % uieditfield (numeric/text) ou uidropdown, a depender da pseudo 
            % classe da coluna. Guarda-se um handle p/ o elemento ativo.
            app.operation1_List.UserData.inputHandle = [];
            app.operation2_List.UserData.inputHandle = [];
        end

        %-----------------------------------------------------------------%
        function updateForm(app)
            switch app.mainApp.General.context.SEARCH.type
                case 'FreeText'
                    app.filteringStrategy.Value = 'Texto livre';
                case 'ColumnFilter'
                    app.filteringStrategy.Value = 'Filtro por coluna';
                otherwise % FreeText+ColumnFilter
                    app.filteringStrategy.Value = 'Texto livre + Filtro por coluna';
            end

            switch app.mainApp.General.context.SEARCH.mode
                case 'tokens'
                    app.config_SearchModeTokenSuggestion.Value = 1;
                otherwise % words
                    app.config_SearchModeListOfWords.Value = 1;
            end

            set(app.config_SearchModePanel.Children, 'Enable', contains(app.filteringStrategy.Value, 'Texto livre'))
            set(app.ColumnFilterPanel,               'Enable', contains(app.filteringStrategy.Value, 'Filtro por coluna'))

            updateTree(app)
        end

        %-----------------------------------------------------------------%
        function updateTree(app)
            if ~isempty(app.columnFilterList.Children)
                delete(app.columnFilterList.Children)
            end

            filterList = getFilterList(app.mainApp.filteringObj, 'SCH');
            if ~isempty(filterList)
                checkedNodes = [];
    
                for ii = 1:numel(filterList)
                    childNode = uitreenode(app.columnFilterList, 'Text', filterList{ii}, 'NodeData', ii, 'ContextMenu', app.ContextMenu);
    
                    if app.mainApp.filteringObj.filterRules.Enable(ii)
                        checkedNodes = [checkedNodes, childNode];
                    end
                end
    
                app.columnFilterList.CheckedNodes = checkedNodes;
            end
        end

        %-----------------------------------------------------------------%
        function [columName, pseudoClass] = inspectColumnData(app)
            symbolicName = app.symbolicNameList.Value;
            [~, symbolicIndex] = ismember(symbolicName, app.symbolicNameList.Items);
            columnIndex = symbolicIndex-1;

            if columnIndex == 0
                columName   = '';
                pseudoClass = '';
            else
                columName   = app.symbolicNameList.UserData.columnNames{columnIndex};
                pseudoClass = app.symbolicNameList.UserData.pseudoClasses{columnIndex};
            end
        end

        %-----------------------------------------------------------------%
        function categories = getCategories(app, columnName)
            categories = {};
            categoriesIndex = find(strcmp({app.mainApp.schDataCategories.columnName}, columnName), 1);
            if ~isempty(categoriesIndex)
                categories = app.mainApp.schDataCategories(categoriesIndex).categories;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context)
            
            try
                appEngine.boot(app, app.Role, mainApp, callingApp)
                
                app.inputArgs = struct('context', context);
                initialLayout(app)
                updateForm(app)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Callback function
        function onSearchModeChanged(app, event)
            
            switch event.Source
                case app.filteringStrategy
                    switch app.filteringStrategy.Value
                        case 'Texto livre'
                            app.mainApp.General.context.SEARCH.type = 'FreeText';
                        case 'Filtro por coluna'
                            app.mainApp.General.context.SEARCH.type = 'ColumnFilter';
                        otherwise % 'Texto livre + Filtro por coluna'
                            app.mainApp.General.context.SEARCH.type = 'FreeText+ColumnFilter';
                    end

                case app.config_SearchModePanel            
                    switch app.config_SearchModePanel.SelectedObject
                        case app.config_SearchModeTokenSuggestion
                            app.mainApp.General.context.SEARCH.mode     = 'tokens';
                            app.mainApp.General.context.SEARCH.function = 'strcmp';
        
                        otherwise % app.config_SearchModeListOfWords
                            app.mainApp.General.context.SEARCH.mode     = 'words';
                            app.mainApp.General.context.SEARCH.function = 'contains';
                    end
            end

            app.mainApp.General_I.context.SEARCH = app.mainApp.General.context.SEARCH;
            appEngine.util.generalSettingsSave(class.Constants.appName, app.mainApp.rootFolder, app.mainApp.General_I, app.mainApp.executionMode)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'onSearchModeChanged')
            updateForm(app)

        end

        % Callback function: not associated with a component
        function onFilterColumnChanged(app, event)
            
            restartState(app)

            [columnName, pseudoClass] = inspectColumnData(app);
            app.symbolicNameList.UserData.selected = struct('columnName', columnName, 'pseudoClass', pseudoClass);

            if isempty(pseudoClass)
                operations = {};
            else
                operations = tableFiltering.getFilterCapabilities(pseudoClass);
            end
            
            app.operation1_List.Items = operations;
            set(app.operation2_List, 'Items', [{''}, operations], 'Value', '')

            if ~isempty(operations)
                app.operation1_List.Value = app.operation1_List.Items{1};
                onFilterOperatorChanged(app, struct('Source', app.operation1_List))
                onFilterOperatorChanged(app, struct('Source', app.operation2_List))
            end

            app.columnFilterAdd.Enable = ~isempty(operations);

        end

        % Callback function: not associated with a component
        function onFilterOperatorChanged(app, event)
            
            switch event.Source
                case app.operation1_List
                    valueHandles = [ ...
                        app.value1_Date, ...
                        app.value1_Numeric, ...
                        app.value1_TextFree, ...
                        app.value1_TextList ...
                    ];
                    
                case app.operation2_List
                    valueHandles = [ ...
                        app.value2_Date, ...
                        app.value2_Numeric, ...
                        app.value2_TextFree, ...
                        app.value2_TextList ...
                    ];
            end
            tagHandles  = arrayfun(@(x) x.Tag, valueHandles, 'UniformOutput', false);

            columnName  = app.symbolicNameList.UserData.selected.columnName;
            pseudoClass = app.symbolicNameList.UserData.selected.pseudoClass;
            categories  = getCategories(app, columnName);

            switch pseudoClass
                case 'cellstr'
                    [~, tagIndex] = ismember('textFree', tagHandles);
                  % optionalArgs  = {'Value', ''};
                    optionalArgs  = {};

                case 'numeric'
                    [~, tagIndex] = ismember('numeric', tagHandles);
                    optionalArgs  = {};

                case 'datetime'
                    [~, tagIndex] = ismember('datePicker', tagHandles);
                    optionalArgs  = {};

                case 'categorical'
                    % Se a coluna tiver mais de 500 categorias, apresenta-se 
                    % como uieditfield (text) ao invés de dropdown.
                    if isempty(categories)
                        [~, tagIndex] = ismember('textFree', tagHandles);
                        optionalArgs  = {};
                    else
                        [~, tagIndex] = ismember('textList', tagHandles);
                        optionalArgs  = {'Items', [{''}; categories]};
                    end
            end

            event.Source.UserData.inputHandle = valueHandles(tagIndex);
            set(valueHandles(tagIndex), 'Visible', 1, optionalArgs{:})
            set(setdiff(valueHandles, valueHandles(tagIndex)), 'Visible', 0)
            
        end

        % Callback function: not associated with a component
        function onFilterAddImageClicked(app, event)
            
            columnName = app.symbolicNameList.UserData.selected.columnName;            
            operators  = {app.operation1_List.Value};
            values     = {app.operation1_List.UserData.inputHandle.Value};
            connector  = app.operation2_LogicalGrid.SelectedObject.Text;

            if ~isempty(app.operation2_List.Value) && (~strcmp(app.operation1_List.Value, app.operation2_List.Value) || ~isequal(app.operation1_List.UserData.inputHandle.Value, app.operation2_List.UserData.inputHandle.Value))
                operators = [operators, {app.operation2_List.Value}];
                values    = [values, {app.operation2_List.UserData.inputHandle.Value}];
            end

            try
                addFilterRule(app.mainApp.filteringObj, columnName, operators, values, connector);
            catch ME
                ui.Dialog(app.UIFigure, 'warning', ME.message);
                return
            end
            updateTree(app)

            ipcMainMatlabCallsHandler(app.mainApp, app, 'onColumnFilterChanged')

        end

        % Menu selected function: columnFilterDel
        function onFilterDelImageClicked(app, event)
            
            selectedNodes = app.columnFilterList.SelectedNodes;

            if ~isempty(selectedNodes)
                removeFilterRule(app.mainApp.filteringObj, [selectedNodes.NodeData])
                updateTree(app)

                ipcMainMatlabCallsHandler(app.mainApp, app, 'onColumnFilterChanged')
            end

        end

        % Callback function: not associated with a component
        function onColumnFilterCheckedNodesChanged(app, event)

            checkedNodes = [];            
            if ~isempty(app.columnFilterList.CheckedNodes)
                checkedNodes = [app.columnFilterList.CheckedNodes.NodeData];
            end

            initialEnableArray = app.mainApp.filteringObj.filterRules.Enable;
            currentEnableArray = zeros(height(initialEnableArray), 1, 'logical');
            if ~isempty(checkedNodes)
                currentEnableArray(checkedNodes) = true;
            end

            if ~isequal(initialEnableArray, currentEnableArray)
                toogleFilterRule(app.mainApp.filteringObj, currentEnableArray)
                ipcMainMatlabCallsHandler(app.mainApp, app, 'onColumnFilterChanged')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Title = uilabel(app.GridLayout);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b>Configurações de filtragem e exibição</b>'; '<font style="color: gray; font-size: 10px;">Defina como os filtros serão aplicados para refinar os resultados exibidos</font>'};
            app.ColumnFilterLabel = uilabel(app.GridLayout);
            app.ColumnFilterLabel.Text = 'Filtro por coluna';
            app.ColumnFilterPanel = uipanel(app.GridLayout);
            app.ColumnFilterGrid = uigridlayout(app.ColumnFilterPanel);
            app.SymbolicNameList = uidropdown(app.ColumnFilterGrid);
            app.SymbolicNameList.Items = {};
            app.SymbolicNameList.Value = {};
            app.Operation1_List = uidropdown(app.ColumnFilterGrid);
            app.Operation1_List.Items = {};
            app.Operation1_List.Value = {};
            app.Value1_Date = uidatepicker(app.ColumnFilterGrid);
            app.Value1_Date.Editable = 'off';
            app.Value1_Date.Tag = 'datePicker';
            app.Value1_Date.Visible = 'off';
            app.Value1_Numeric = uieditfield(app.ColumnFilterGrid, 'numeric');
            app.Value1_Numeric.Tag = 'numeric';
            app.Value1_Numeric.Visible = 'off';
            app.Value1_Numeric.Value = 1;
            app.Value1_TextList = uidropdown(app.ColumnFilterGrid);
            app.Value1_TextList.Items = {''};
            app.Value1_TextList.Editable = 'on';
            app.Value1_TextList.Tag = 'textList';
            app.Value1_TextList.Visible = 'off';
            app.Value1_TextList.Value = '';
            app.Value1_TextFree = uieditfield(app.ColumnFilterGrid, 'text');
            app.Value1_TextFree.Tag = 'textFree';
            app.Operation2_LogicalGrid = uibuttongroup(app.ColumnFilterGrid);
            app.Operation2_LogicalAnd = uiradiobutton(app.Operation2_LogicalGrid);
            app.Operation2_LogicalAnd.Text = 'E';
            app.Operation2_LogicalAnd.Value = true;
            app.Operation2_LogicalOr = uiradiobutton(app.Operation2_LogicalGrid);
            app.Operation2_LogicalOr.Text = 'Ou';
            app.Operation2_List = uidropdown(app.ColumnFilterGrid);
            app.Operation2_List.Items = {};
            app.Operation2_List.Value = {};
            app.Value2_Date = uidatepicker(app.ColumnFilterGrid);
            app.Value2_Date.Editable = 'off';
            app.Value2_Date.Tag = 'datePicker';
            app.Value2_Date.Visible = 'off';
            app.Value2_Numeric = uieditfield(app.ColumnFilterGrid, 'numeric');
            app.Value2_Numeric.Tag = 'numeric';
            app.Value2_Numeric.Visible = 'off';
            app.Value2_Numeric.Value = 1;
            app.Value2_TextList = uidropdown(app.ColumnFilterGrid);
            app.Value2_TextList.Items = {''};
            app.Value2_TextList.Editable = 'on';
            app.Value2_TextList.Tag = 'textList';
            app.Value2_TextList.Visible = 'off';
            app.Value2_TextList.Value = '';
            app.Value2_TextFree = uieditfield(app.ColumnFilterGrid, 'text');
            app.Value2_TextFree.Tag = 'textFree';
            app.ColumnFilterAdd = uiimage(app.ColumnFilterGrid);
            app.ColumnFilterAdd.Enable = 'off';
            app.ColumnFilterAdd.ImageSource = 'Add_16.png';
            app.ColumnFilterList = uitree(app.ColumnFilterGrid, 'checkbox');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.Tag = 'auxApp.dockFilterSetup';
            app.columnFilterDel = uimenu(app.ContextMenu);
            app.columnFilterDel.MenuSelectedFcn = createCallbackFcn(app, @onFilterDelImageClicked, true);
            app.columnFilterDel.Text = '❌ Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockCustomsFilter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end