classdef dockCustomsManageRules < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        Title       matlab.ui.control.Label
        UITable     matlab.ui.control.Table
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
        jsBackDoor
        progressDialog
        projectData
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function updateTable(app)
            rules = app.projectData.customsRules;
            app.UITable.Data = struct2table(rules);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context)
            
            try
                appEngine.boot(app, app.Role, mainApp, callingApp)
                updateTable(app)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end            
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            delete(app)
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'ID'; 'NOME'; 'CATEGORIA'; 'DECISÃO SUGERIDA'; 'CONFIANÇA'; 'PESO'; 'PRIORIDADE'; 'PALAVRAS-CHAVE'; 'PALAVRAS REFORÇO'; 'PALAVRAS EXCEÇÃO'; 'MOTIVO'; 'ATIVO'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = [false false false true true true true true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Title = uilabel(app.GridLayout);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b>Configurações de filtragem e exibição</b>'; '<font style="color: gray; font-size: 10px;">Defina como os filtros serão aplicados para refinar os resultados exibidos</font>'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockCustomsManageRules(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end