classdef dockFilterSetup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Document                    matlab.ui.container.GridLayout
        SecundaryPanelLabel         matlab.ui.control.Label
        SecundaryPanel              matlab.ui.container.Panel
        SecundaryGrid               matlab.ui.container.GridLayout
        SecundaryTextListValue      matlab.ui.control.DropDown
        SecundaryTextFreeValue      matlab.ui.control.EditField
        SecundaryDateTime2          matlab.ui.control.EditField
        SecundaryDateTimeSeparator  matlab.ui.control.Label
        SecundaryDateTime1          matlab.ui.control.EditField
        SecundaryOperation          matlab.ui.control.DropDown
        SecundaryColumn             matlab.ui.control.DropDown
        SecundaryAddFilter          matlab.ui.control.Image
        SecundaryListOfFilters      matlab.ui.control.ListBox
        btnOK                       matlab.ui.control.Button
        btnClose                    matlab.ui.control.Image
        ContextMenu                 matlab.ui.container.ContextMenu
        ExcluirMenu                 matlab.ui.container.Menu
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Container
        isDocked = true

        mainApp
        callingApp
    end
    
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function initialValues(app)
            allColumns      = app.mainApp.General.ui.searchTable.name;
            [~, sortIndex]  = sort(lower(allColumns));
            GUIAllColumns   = allColumns(sortIndex);

            app.SecundaryColumn.Items = GUIAllColumns;
            SecundaryColumnValueChanged(app)

            app.SecundaryListOfFilters.Items = FilterList(app.mainApp.filteringObj, 'SCH');
        end

        %-----------------------------------------------------------------%
        function filter = SecundaryFilterType(app, columnName)
            index  = find(strcmp(app.mainApp.General.ui.searchTable.name, columnName), 1);
            filter = app.mainApp.General.ui.searchTable.filter{index};
        end

       %-----------------------------------------------------------------%
        function [value, msgWarning] = SecundaryFilterValue(app)
            value      = [];
            msgWarning = '';

            columnName = app.SecundaryColumn.Value;
            filterType = SecundaryFilterType(app, columnName);
            operation  = app.SecundaryOperation.Value;

            switch filterType
                case 'datetime'
                    switch operation
                        case {'=', '≠', '<', '≤', '>', '≥'}
                            value = datetime(app.SecundaryDateTime1.Value, 'InputFormat', 'dd/MM/yyyy', 'Format', 'dd/MM/yyyy');
                            
                        case {'><', '<>'}
                            value = datetime({app.SecundaryDateTime1.Value, app.SecundaryDateTime2.Value}, 'InputFormat', 'dd/MM/yyyy', 'Format', 'dd/MM/yyyy');

                        case {'⊃', '⊅'}
                            try
                                value = cellfun(@(x) datetime(x, "InputFormat", 'dd/MM/yyyy', 'Format', 'dd/MM/yyyy'), strtrim(strsplit(app.SecundaryTextFreeValue.Value, ',')));
                            catch ME
                                app.SecundaryTextFreeValue.Value = '';

                                msgWarning = ME.message;
                                return
                            end
                    end

                case 'freeText'
                    value = strtrim(strsplit(app.SecundaryTextFreeValue.Value, ','));
                    if isscalar(value)
                        value = char(value);
                    end

                case 'listOfText'
                    value = app.SecundaryTextListValue.Value;
            end

            if isempty(value)
                msgWarning = 'Valor inválido.';
            end
        end

        %-----------------------------------------------------------------%
        function CallingMainApp(app, updateFlag, returnFlag)
            ipcMainMatlabCallsHandler(app.mainApp, app, 'SEARCH:FILTERSETUP', updateFlag, returnFlag)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp)
            
            app.mainApp    = mainApp;
            app.callingApp = callingApp;

            initialValues(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcnCallFromPopupApp', 'mainApp', 'auxApp.dockFilterSetup')
            delete(app)
            
        end

        % Value changed function: SecundaryColumn
        function SecundaryColumnValueChanged(app, event)

            columName  = app.SecundaryColumn.Value;
            filterType = SecundaryFilterType(app, columName);

            switch filterType
                case 'datetime'
                    Operations = {'=', '≠', '<', '≤', '>', '≥', '><', '<>'};

                case 'freeText'
                    Operations = {'=', '≠', '⊃', '⊅'};

                case 'listOfText'
                    Operations = {'=', '≠'};
            end
            app.SecundaryOperation.Items = Operations;

            app.SecundaryOperation.Value = app.SecundaryOperation.Items{1};
            SecundaryOperationValueChanged(app)
            
        end

        % Value changed function: SecundaryOperation
        function SecundaryOperationValueChanged(app, event)

            columnName = app.SecundaryColumn.Value;
            filterType = SecundaryFilterType(app, columnName);
            operation  = app.SecundaryOperation.Value;

            switch filterType
                case 'datetime'
                    switch operation
                        case {'=', '≠', '<', '≤', '>', '≥'}
                            set(app.SecundaryDateTime1,         'Visible', 1)
                            set(app.SecundaryDateTimeSeparator, 'Visible', 0)
                            set(app.SecundaryDateTime2,         'Visible', 0)
                            set(app.SecundaryTextFreeValue,     'Visible', 0)
                            set(app.SecundaryTextListValue,     'Visible', 0, 'Items', {})
                            
                        case {'><', '<>'}
                            set(app.SecundaryDateTime1,         'Visible', 1)
                            set(app.SecundaryDateTimeSeparator, 'Visible', 1)
                            set(app.SecundaryDateTime2,         'Visible', 1)
                            set(app.SecundaryTextFreeValue,     'Visible', 0)
                            set(app.SecundaryTextListValue,     'Visible', 0, 'Items', {})
                    end

                case 'freeText'
                    set(app.SecundaryDateTime1,         'Visible', 0)
                    set(app.SecundaryDateTimeSeparator, 'Visible', 0)
                    set(app.SecundaryDateTime2,         'Visible', 0)
                    set(app.SecundaryTextFreeValue,     'Visible', 1)
                    set(app.SecundaryTextListValue,     'Visible', 0, 'Items', {})

                case 'listOfText'
                    try
                        primaryIndex = app.mainApp.search_Table.UserData.primaryIndex;
                        columnItems  = [{''}; cellstr(unique(app.mainApp.rawDataTable{primaryIndex, columnName}))];
                    catch
                        columnItems = {};
                    end

                    set(app.SecundaryDateTime1,         'Visible', 0)
                    set(app.SecundaryDateTimeSeparator, 'Visible', 0)
                    set(app.SecundaryDateTime2,         'Visible', 0)
                    set(app.SecundaryTextFreeValue,     'Visible', 0)
                    set(app.SecundaryTextListValue,     'Visible', 1, 'Items', columnItems)
            end

        end

        % Image clicked function: SecundaryAddFilter
        function SecundaryAddFilterImageClicked(app, event)
            
            primaryIndex = app.mainApp.search_Table.UserData.primaryIndex;
            if isempty(primaryIndex)
                appUtil.modalWindow(app.UIFigure, 'warning', 'A filtragem secundária é aplicável apenas após a realização de uma pesquisa (filtragem primária), e desde que tenha retornado algum registro dessa pesquisa.');
                return
            end

            % Afere os valores do novo filtro, validando-os.
            Column    = app.SecundaryColumn.Value;
            Operation = app.SecundaryOperation.Value;
            
            [Value, msgWarning] = SecundaryFilterValue(app);
            if ~isempty(msgWarning)
                appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                return
            end

            % Adiciona um novo filtro à lista de filtros secundários.
            msgWarning = addFilterRule(app.mainApp.filteringObj, Column, Operation, Value);
            if ~isempty(msgWarning)
                appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                return
            end

            % Filtra...
            CallingMainApp(app, true, true)
            initialValues(app)

        end

        % Callback function: btnClose, btnOK
        function ButtonPushed(app, event)
            
            CallingMainApp(app, false, false)
            closeFcn(app)

        end

        % Menu selected function: ExcluirMenu
        function ExcluirMenuSelected(app, event)
            
            selectedFilter = app.SecundaryListOfFilters.Value;

            if ~isempty(selectedFilter)
                idxFilter = find(ismember(app.SecundaryListOfFilters.Items, selectedFilter));
                removeFilterRule(app.mainApp.filteringObj, idxFilter);
                
                CallingMainApp(app, true, true)
                initialValues(app)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.btnClose = uiimage(app.GridLayout);
            app.btnClose.ImageClickedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnClose.Tag = 'Close';
            app.btnClose.ImageSource = 'Delete_12SVG.svg';
            app.Document = uigridlayout(app.GridLayout);
            app.btnOK = uibutton(app.Document, 'push');
            app.btnOK.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.btnOK.Tag = 'OK';
            app.btnOK.Text = 'OK';
            app.SecundaryListOfFilters = uilistbox(app.Document);
            app.SecundaryListOfFilters.Items = {};
            app.SecundaryListOfFilters.Multiselect = 'on';
            app.SecundaryListOfFilters.Value = {};
            app.SecundaryAddFilter = uiimage(app.Document);
            app.SecundaryAddFilter.ImageClickedFcn = createCallbackFcn(app, @SecundaryAddFilterImageClicked, true);
            app.SecundaryAddFilter.ImageSource = 'Sum_36.png';
            app.SecundaryPanel = uipanel(app.Document);
            app.SecundaryGrid = uigridlayout(app.SecundaryPanel);
            app.SecundaryColumn = uidropdown(app.SecundaryGrid);
            app.SecundaryColumn.Items = {};
            app.SecundaryColumn.ValueChangedFcn = createCallbackFcn(app, @SecundaryColumnValueChanged, true);
            app.SecundaryColumn.Value = {};
            app.SecundaryOperation = uidropdown(app.SecundaryGrid);
            app.SecundaryOperation.Items = {'=', '≠', '⊃', '⊅', '<', '≤', '>', '≥', '><', '<>'};
            app.SecundaryOperation.ValueChangedFcn = createCallbackFcn(app, @SecundaryOperationValueChanged, true);
            app.SecundaryOperation.Value = '=';
            app.SecundaryDateTime1 = uieditfield(app.SecundaryGrid, 'text');
            app.SecundaryDateTime1.CharacterLimits = [10 10];
            app.SecundaryDateTime1.Visible = 'off';
            app.SecundaryDateTime1.Placeholder = 'dd/mm/yyyy';
            app.SecundaryDateTimeSeparator = uilabel(app.SecundaryGrid);
            app.SecundaryDateTimeSeparator.Visible = 'off';
            app.SecundaryDateTimeSeparator.Text = '-';
            app.SecundaryDateTime2 = uieditfield(app.SecundaryGrid, 'text');
            app.SecundaryDateTime2.CharacterLimits = [10 10];
            app.SecundaryDateTime2.Visible = 'off';
            app.SecundaryDateTime2.Placeholder = 'dd/mm/yyyy';
            app.SecundaryTextFreeValue = uieditfield(app.SecundaryGrid, 'text');
            app.SecundaryTextFreeValue.Visible = 'off';
            app.SecundaryTextListValue = uidropdown(app.SecundaryGrid);
            app.SecundaryTextListValue.Items = {};
            app.SecundaryTextListValue.Visible = 'off';
            app.SecundaryTextListValue.Value = {};
            app.SecundaryPanelLabel = uilabel(app.Document);
            app.SecundaryPanelLabel.Text = 'Filtragem secundária:';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExcluirMenu = uimenu(app.ContextMenu);
            app.ExcluirMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcluirMenuSelected, true);
            app.ExcluirMenu.Text = 'Excluir';
            % Assign app.ContextMenu
            app.SecundaryListOfFilters.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockFilterSetup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end