classdef dockSearchAddSelectedToBucket < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        Button             matlab.ui.control.Button
        Tree               matlab.ui.container.CheckBoxTree
        TreeLabel          matlab.ui.control.Label
        HomologationPanel  matlab.ui.container.Panel
        HomologationGrid   matlab.ui.container.GridLayout
        Homologation       matlab.ui.control.Label
        HomologationIcon   matlab.ui.control.Image
        Title              matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
    end
    
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function updateForm(app, schDetailedIdxs)
            homologation = app.mainApp.schData.detailed.("Homologação"){schDetailedIdxs(1)};
            app.Homologation.Text = sprintf('<font style="color:gray;">Homologação nº</font> <b>%s</b>', homologation);

            checkedNodes = matlab.ui.container.TreeNode.empty;

            for ii = 1:numel(schDetailedIdxs)
                idx = schDetailedIdxs(ii);

                nodeText = sprintf([ ...
                    '<br><font style="color: gray; font-size: 10px;">Solicitante:</font> %s<br>' ...
                    '<font style="color: gray; font-size: 10px;">Fabricante:</font> %s<br>' ...
                    '<font style="color: gray; font-size: 10px;">Modelo:</font> %s<br>' ...
                    '<font style="color: gray; font-size: 10px;">Nome comercial:</font> %s<br><br>', ...
                ], app.mainApp.schData.detailed.("Solicitante"){idx}, ...
                   app.mainApp.schData.detailed.("Fabricante"){idx}, ...
                   app.mainApp.schData.detailed.("Modelo"){idx}, ...
                   app.mainApp.schData.detailed.("Nome Comercial"){idx} ...
                );

                treeNode = uitreenode(app.Tree, 'Text', nodeText, 'NodeData', idx);

                [~, productHash] = model.ProjectBase.initializeInspectedProduct('Homologado', app.mainApp.General, app.mainApp.schData.detailed, idx);    
                if ismember(productHash, app.mainApp.projectData.inspectedProducts.("Hash"))
                    checkedNodes(end+1) = treeNode;
                end
            end

            if ~isempty(checkedNodes)
                app.Tree.CheckedNodes = checkedNodes;
            end

            if numel(app.Tree.Children) == numel(checkedNodes)
                app.Tree.Enable = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context, schDetailedIdxs)
            
            try
                appEngine.boot(app, app.Role, mainApp, callingApp)
                
                addStyle(app.Tree, uistyle('Interpreter', 'html'))
                updateForm(app, schDetailedIdxs)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Callback function: Tree
        function onTreeCheckedNodesChanged(app, event)
            
            app.Button.Enable = ~isempty(app.Tree.CheckedNodes);
            
        end

        % Button pushed function: Button
        function onButtonPushed(app, event)
            
            if isempty(app.Tree.CheckedNodes)
                onTreeCheckedNodesChanged(app)
                return
            end

            homDetailedIdxs = [app.Tree.CheckedNodes.NodeData];
            ipcMainMatlabCallsHandler(app.mainApp, app, 'onAddSelectedToBucketRequest', homDetailedIdxs)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Title = uilabel(app.GridLayout);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b>Selecionar modelos inspecionados</b>'; '<font style="color: gray; font-size: 10px;">Selecione os modelos deste certificado que devem ser incluídos na lista de produtos inspecionados</font>'};
            app.HomologationPanel = uipanel(app.GridLayout);
            app.HomologationGrid = uigridlayout(app.HomologationPanel);
            app.HomologationIcon = uiimage(app.HomologationGrid);
            app.HomologationIcon.Enable = 'off';
            app.HomologationIcon.ImageSource = 'circuit-board.svg';
            app.Homologation = uilabel(app.HomologationGrid);
            app.Homologation.Interpreter = 'html';
            app.Homologation.Text = '<font style="color:gray;">Homologação nº</font>';
            app.TreeLabel = uilabel(app.GridLayout);
            app.TreeLabel.Text = 'Registros encontrados';
            app.Tree = uitree(app.GridLayout, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @onTreeCheckedNodesChanged, true);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @onButtonPushed, true);
            app.Button.Tag = 'OK';
            app.Button.Enable = 'off';
            app.Button.Text = 'Confirma inclusão';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockSearchAddSelectedToBucket(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end