classdef mobileConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        FiscalizaPanel            matlab.ui.container.Panel
        FiscalizaGrid             matlab.ui.container.GridLayout
        DevToolsButton            matlab.ui.control.Image
        FileCompressionMode       matlab.ui.control.DropDown
        FileCompressionModeLabel  matlab.ui.control.Label
        MacrothemesList           matlab.ui.control.EditField
        MacrothemesButton         matlab.ui.control.Image
        MacrothemesLabel          matlab.ui.control.Label
        FiscalizaRefreshButton    matlab.ui.control.Image
        FiscalizaLabel            matlab.ui.control.Label
        SearchPanel               matlab.ui.container.Panel
        SearchGrid                matlab.ui.container.GridLayout
        NumMinWords               matlab.ui.control.DropDown
        NumMinWordsLabel          matlab.ui.control.Label
        NumMinCharacters          matlab.ui.control.Spinner
        NumMinCharactersLabel     matlab.ui.control.Label
        SearchRefreshButton       matlab.ui.control.Image
        SearchLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryAppMobile'
        Context = 'CONFIG'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        jsBackDoor

        mainApp
        progressDialog
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        defaultValues
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)
                        
                    otherwise
                        ipcMainJSEventsHandler(app.mainApp, event)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app)
            appName = class(app);
            elToModify = {
                app.SearchPanel;
                app.FiscalizaPanel;
                app.NumMinCharacters;
                app.NumMinWords;
                app.MacrothemesList;
                app.FileCompressionMode
            };
            ui.CustomizationBase.getElementsDataTag(elToModify);
    
            try
                sendEventToHTMLSource(app.mainApp.jsBackDoor, 'initializeComponents', { ...
                    struct('appName', appName, 'dataTag', app.SearchPanel.UserData.id,         'generation', 1, 'style', struct('borderRadius', '8px')), ...
                    struct('appName', appName, 'dataTag', app.FiscalizaPanel.UserData.id,      'generation', 1, 'style', struct('borderRadius', '8px')), ...
                    struct('appName', appName, 'dataTag', app.NumMinCharacters.UserData.id,    'selector', '.mwTextField', 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.NumMinWords.UserData.id,         'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.MacrothemesList.UserData.id,     'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.FileCompressionMode.UserData.id, 'generation', 1, 'style', struct('borderColor', '#AEBBC2')) ...
                });
            catch
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            % Lê a versão de "GeneralSettings.json" que vem junto ao
            % projeto (e não a versão armazenada em "ProgramData").
            projectFolder   = appEngine.util.Path(class.Constants.appName, app.mainApp.rootFolder);
            projectFilePath = fullfile(projectFolder, 'GeneralSettings.json');
            projectGeneral  = jsondecode(fileread(projectFilePath));

            app.defaultValues = struct( ...
                'ANALYSIS', struct( ...
                    'minCharacters', projectGeneral.context.SEARCH.minCharacters, ...
                    'minDisplayedTokens', projectGeneral.context.SEARCH.minDisplayedTokens ...
                ), ...
                'REPORT', struct( ...
                    'allowedMacrothemes', {projectGeneral.reportLib.allowedMacrothemes}, ...
                    'outputCompressionMode', projectGeneral.reportLib.outputCompressionMode ...
                ) ...
            );
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            set([app.SearchPanel, app.FiscalizaPanel], 'BorderColor', '#D5DDE1')

            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.DevToolsButton.Visible = 'on';
            end
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            updatePanel(app)
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function updatePanel(app)
            app.NumMinCharacters.Value = app.mainApp.General.context.SEARCH.minCharacters;

            numDisplayedTokens = num2str(app.mainApp.General.context.SEARCH.minDisplayedTokens);
            if ismember(numDisplayedTokens, app.NumMinWords.Items)
                app.NumMinWords.Value = numDisplayedTokens;
            end

            app.MacrothemesButton.UserData.status = false;
            app.MacrothemesButton.ImageSource = 'Edit_32.png';
            set(app.MacrothemesList, 'Editable', 'off', 'FontColor', [0.65,0.65,0.65], 'Value', textFormatGUI.cellstr2ListWithQuotes(app.mainApp.General.reportLib.allowedMacrothemes, 'none'))

            if ismember(app.mainApp.General.reportLib.outputCompressionMode, app.FileCompressionMode.Items)
                app.FileCompressionMode.Value = app.mainApp.General.reportLib.outputCompressionMode;
            end

            app.SearchRefreshButton.Visible = checkEdition(app, 'ANALYSIS');
            app.FiscalizaRefreshButton.Visible = checkEdition(app, 'REPORT');
        end

        %-----------------------------------------------------------------%
        function editionFlag = checkEdition(app, fieldName)
            editionFlag = false;
            currentValues = struct( ...
                'ANALYSIS', struct( ...
                    'minCharacters', app.mainApp.General.context.SEARCH.minCharacters, ...
                    'minDisplayedTokens', app.mainApp.General.context.SEARCH.minDisplayedTokens ...
                ), ...
                'REPORT', struct( ...
                    'allowedMacrothemes', {app.mainApp.General.reportLib.allowedMacrothemes}, ...
                    'outputCompressionMode', app.mainApp.General.reportLib.outputCompressionMode ...
                ) ...
            );

            if ~isequal(currentValues.(fieldName), app.defaultValues.(fieldName))
                editionFlag = true;
            end
        end

        %-----------------------------------------------------------------%
        function saveGeneralSettings(app)
            appEngine.util.generalSettingsSave(class.Constants.appName, app.mainApp.rootFolder, app.mainApp.General_I, app.mainApp.executionMode)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            try
                appEngine.boot(app, app.Role, mainApp)

            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Image clicked function: FiscalizaRefreshButton, 
        % ...and 1 other component
        function onRefreshButtonClicked(app, event)
            
            projectFolder   = appEngine.util.Path(class.Constants.appName, app.mainApp.rootFolder);
            projectFilePath = fullfile(projectFolder, 'GeneralSettings.json');
            projectGeneral  = jsondecode(fileread(projectFilePath));

            switch event.Source
                case app.SearchRefreshButton
                    app.mainApp.General.context.SEARCH = projectGeneral.context.SEARCH;
                    app.mainApp.General_I.context.SEARCH = app.mainApp.General.context.SEARCH;

                case app.FiscalizaRefreshButton
                    app.mainApp.General.reportLib.allowedMacrothemes = app.defaultValues.REPORT.allowedMacrothemes;
                    app.mainApp.General.reportLib.outputCompressionMode = app.defaultValues.REPORT.outputCompressionMode;
                    app.mainApp.General_I.reportLib = app.mainApp.General.reportLib;
            end

            updatePanel(app)
            saveGeneralSettings(app)

        end

        % Value changed function: NumMinCharacters, NumMinWords
        function onSearchParameterValueChanged(app, event)
            
            switch event.Source
                case app.NumMinCharacters
                    app.mainApp.General.context.SEARCH.minCharacters = app.NumMinCharacters.Value;

                case app.NumMinWords
                    app.mainApp.General.context.SEARCH.minDisplayedTokens = str2double(app.NumMinWords.Value);
            end

            app.mainApp.General_I.context.SEARCH = app.mainApp.General.context.SEARCH;
            
            updatePanel(app)
            saveGeneralSettings(app)

        end

        % Callback function: MacrothemesButton, MacrothemesList
        function onMacrothemeListChanged(app, event)
            
            switch event.Source
                case app.MacrothemesButton
                    app.MacrothemesButton.UserData.status = ~app.MacrothemesButton.UserData.status;
                    if app.MacrothemesButton.UserData.status
                        app.MacrothemesButton.ImageSource = 'Edit_32Filled.png';
                        set(app.MacrothemesList, 'Editable', 'on', 'FontColor', [0,0,0])
                    else
                        app.MacrothemesButton.ImageSource = 'Edit_32.png';
                        set(app.MacrothemesList, 'Editable', 'off', 'FontColor', [0.65,0.65,0.65])
                    end

                case app.MacrothemesList
                    parts = strsplit(event.Value, ',');
                    try
                        requiredMacrothemes = unique(strtrim(extractBetween(parts, '"', '"')));
                    catch
                        app.MacrothemesList.Value = event.PreviousValue;
                        return
                    end

                    currentMacrothemes = sort(app.mainApp.General.reportLib.allowedMacrothemes);

                    if numel(requiredMacrothemes) ~= numel(parts) || isequal(currentMacrothemes, requiredMacrothemes)
                        app.MacrothemesList.Value = event.PreviousValue;
                        return
                    end

                    msgQuestion = sprintf([ ...
                        'LISTA ATUAL:\n%s\n\nLISTA PROPOSTA:\n%s\n\n' ...
                        'Confirma a troca da lista de macrotemas?' ...
                    ], textFormatGUI.cellstr2ListWithQuotes(currentMacrothemes, 'none'), textFormatGUI.cellstr2ListWithQuotes(requiredMacrothemes, 'none'));

                    userSelection = ui.Dialog(app.UIFigure, 'uiconfirm', msgQuestion, {'Sim', 'Não'}, 1, 2);
                    if userSelection == "Não"
                        app.MacrothemesList.Value = event.PreviousValue;
                        return
                    end

                    app.mainApp.General.reportLib.allowedMacrothemes = requiredMacrothemes;
                    app.mainApp.General_I.reportLib = app.mainApp.General.reportLib;

                    updatePanel(app)
                    saveGeneralSettings(app)
            end

        end

        % Value changed function: FileCompressionMode
        function onCompressionModeValueChanged(app, event)
            
            app.mainApp.General.reportLib.outputCompressionMode = event.Value;
            app.mainApp.General_I.reportLib = app.mainApp.General.reportLib;

            updatePanel(app)
            saveGeneralSettings(app)
            
        end

        % Image clicked function: DevToolsButton
        function onDevToolsButtonClicked(app, event)
                        
            webWin = struct(struct(struct(app.UIFigure).Controller).PlatformHost).CEF;
            webWin.openDevTools();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SearchLabel = uilabel(app.GridLayout);
            app.SearchLabel.Text = 'Algoritmo de sugestão de tokens';
            app.SearchRefreshButton = uiimage(app.GridLayout);
            app.SearchRefreshButton.ImageClickedFcn = createCallbackFcn(app, @onRefreshButtonClicked, true);
            app.SearchRefreshButton.Visible = 'off';
            app.SearchRefreshButton.ImageSource = 'Refresh_18.png';
            app.SearchPanel = uipanel(app.GridLayout);
            app.SearchGrid = uigridlayout(app.SearchPanel);
            app.NumMinCharactersLabel = uilabel(app.SearchGrid);
            app.NumMinCharactersLabel.Text = 'Qtd. mínima de caracteres para ativar sugestões:';
            app.NumMinCharacters = uispinner(app.SearchGrid);
            app.NumMinCharacters.Limits = [2 4];
            app.NumMinCharacters.RoundFractionalValues = 'on';
            app.NumMinCharacters.ValueDisplayFormat = '%d';
            app.NumMinCharacters.ValueChangedFcn = createCallbackFcn(app, @onSearchParameterValueChanged, true);
            app.NumMinCharacters.Value = 2;
            app.NumMinWordsLabel = uilabel(app.SearchGrid);
            app.NumMinWordsLabel.Interpreter = 'html';
            app.NumMinWordsLabel.Text = {'Qtd. mínima de sugestões apresentadas para seleção:'; '<font style="font-size: 11px; color:gray;">(resultados que mais se aproximam do digitado)</font>'};
            app.NumMinWords = uidropdown(app.SearchGrid);
            app.NumMinWords.Items = {'20', '50'};
            app.NumMinWords.ValueChangedFcn = createCallbackFcn(app, @onSearchParameterValueChanged, true);
            app.NumMinWords.Value = '20';
            app.FiscalizaLabel = uilabel(app.GridLayout);
            app.FiscalizaLabel.Text = 'eFiscaliza';
            app.FiscalizaRefreshButton = uiimage(app.GridLayout);
            app.FiscalizaRefreshButton.ImageClickedFcn = createCallbackFcn(app, @onRefreshButtonClicked, true);
            app.FiscalizaRefreshButton.Visible = 'off';
            app.FiscalizaRefreshButton.ImageSource = 'Refresh_18.png';
            app.FiscalizaPanel = uipanel(app.GridLayout);
            app.FiscalizaGrid = uigridlayout(app.FiscalizaPanel);
            app.MacrothemesLabel = uilabel(app.FiscalizaGrid);
            app.MacrothemesLabel.Text = 'Macrotemas aceitos para fins de elaboração do relato:';
            app.MacrothemesButton = uiimage(app.FiscalizaGrid);
            app.MacrothemesButton.ImageClickedFcn = createCallbackFcn(app, @onMacrothemeListChanged, true);
            app.MacrothemesButton.ImageSource = 'Edit_32.png';
            app.MacrothemesList = uieditfield(app.FiscalizaGrid, 'text');
            app.MacrothemesList.ValueChangedFcn = createCallbackFcn(app, @onMacrothemeListChanged, true);
            app.MacrothemesList.Editable = 'off';
            app.FileCompressionModeLabel = uilabel(app.FiscalizaGrid);
            app.FileCompressionModeLabel.Text = 'Compressão aplicada ao arquivo de saída do projeto?';
            app.FileCompressionMode = uidropdown(app.FiscalizaGrid);
            app.FileCompressionMode.Items = {'Não', 'Sim'};
            app.FileCompressionMode.ValueChangedFcn = createCallbackFcn(app, @onCompressionModeValueChanged, true);
            app.FileCompressionMode.Value = 'Sim';
            app.DevToolsButton = uiimage(app.FiscalizaGrid);
            app.DevToolsButton.ImageClickedFcn = createCallbackFcn(app, @onDevToolsButtonClicked, true);
            app.DevToolsButton.Visible = 'off';
            app.DevToolsButton.ImageSource = 'Debug_18.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mobileConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end