classdef mobileProductInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        ProductStatusGrid     matlab.ui.container.GridLayout
        Note                  matlab.ui.control.TextArea
        NoteLabel             matlab.ui.control.Label
        Lacre                 matlab.ui.control.EditField
        LacreLabel            matlab.ui.control.Label
        PLAI                  matlab.ui.control.EditField
        PLAILabel             matlab.ui.control.Label
        ViolationType         matlab.ui.control.DropDown
        ViolationTypeLabel    matlab.ui.control.Label
        Corrigible            matlab.ui.control.DropDown
        CorrigibleLabel       matlab.ui.control.Label
        Situation             matlab.ui.control.DropDown
        SituationLabel        matlab.ui.control.Label
        QtdRetida             matlab.ui.control.NumericEditField
        QtdRetidaLabel        matlab.ui.control.Label
        QtdApreendida         matlab.ui.control.NumericEditField
        QtdApreendidaLabel    matlab.ui.control.Label
        QtdLacrada            matlab.ui.control.NumericEditField
        QtdLacradaLabel       matlab.ui.control.Label
        QtdAnunciada          matlab.ui.control.NumericEditField
        QtdAnunciadaLabel     matlab.ui.control.Label
        QtdEstoque            matlab.ui.control.NumericEditField
        QtdEstoqueLabel       matlab.ui.control.Label
        QtdUso                matlab.ui.control.NumericEditField
        QtdUsoLabel           matlab.ui.control.Label
        QtdVendida            matlab.ui.control.NumericEditField
        QtdVendidaLabel       matlab.ui.control.Label
        UnitPriceSource       matlab.ui.control.EditField
        UnitPriceSourceLabel  matlab.ui.control.Label
        UnitPrice             matlab.ui.control.NumericEditField
        UnitPriceLabel        matlab.ui.control.Label
        Interference          matlab.ui.control.CheckBox
        InUse                 matlab.ui.control.CheckBox
        RF                    matlab.ui.control.CheckBox
        EvidenceLabel         matlab.ui.control.Label
        CodAduana             matlab.ui.control.EditField
        CodAduanaLabel        matlab.ui.control.Label
        Importador            matlab.ui.control.EditField
        ImportadorLabel       matlab.ui.control.Label
        Model                 matlab.ui.control.EditField
        ModelLabel            matlab.ui.control.Label
        Manufacturer          matlab.ui.control.EditField
        ManufacturerLabel     matlab.ui.control.Label
        Subtype               matlab.ui.control.DropDown
        SubtypeLabel          matlab.ui.control.Label
        Type                  matlab.ui.control.DropDown
        TypeLabel             matlab.ui.control.Label
        HomologationPanel     matlab.ui.container.Panel
        HomologationGrid      matlab.ui.container.GridLayout
        ProductNext           matlab.ui.control.Image
        ProductPrevious       matlab.ui.control.Image
        ProductPosition       matlab.ui.control.Label
        Homologation          matlab.ui.control.Label
        HomologationIcon      matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockAppMobile'
        AppHandleNameInBase = ''
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
        projectData
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        inputArgs
    end


    methods
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'onMobileProductWarningRequested'
                        showInspectedProductWarnings(app)

                    otherwise
                        ipcMainJSEventsHandler(app.mainApp, event)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function ipcSecondaryMatlabCallsHandler(app, callingApp, eventName, varargin)
            try
                switch class(callingApp)
                    case {'mainApp.mobile', 'mainApp.mobile_exported'}
                        switch eventName
                            case 'updateForm'
                                currentProductIdx = varargin{1};
                                app.inputArgs.selectedProductIdx = currentProductIdx;
                                updateForm(app, currentProductIdx)

                            otherwise
                                error('auxApp:mobileProducts:UnexpectedCall', 'Unexpected call "%s"', eventName)
                        end
    
                    otherwise
                        error('auxApp:mobileProducts:UnexpectedCaller', 'Unexpected caller "%s"', class(callingApp))
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end
    end
    
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function applyJSCustomizations(app)
            appName = class(app);
            elToModify = {
                app.HomologationPanel;
                app.Type;
                app.Subtype;
                app.Manufacturer;
                app.Model;
                app.Importador;
                app.CodAduana;
                app.UnitPrice;
                app.UnitPriceSource;
                app.QtdVendida;
                app.QtdUso;
                app.QtdEstoque;
                app.QtdAnunciada;
                app.QtdLacrada;
                app.QtdApreendida;
                app.QtdRetida;
                app.Situation;
                app.ViolationType;
                app.Corrigible;
                app.Lacre;
                app.PLAI;
                app.Note
            };
            ui.CustomizationBase.getElementsDataTag(elToModify);
    
            try
                sendEventToHTMLSource(app.mainApp.jsBackDoor, 'initializeComponents', { ...
                    struct('appName', appName, 'dataTag', app.HomologationPanel.UserData.id, 'generation', 1, 'style', struct('borderRadius', '8px')), ...
                    struct('appName', appName, 'dataTag', app.Type.UserData.id,              'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Subtype.UserData.id,           'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Manufacturer.UserData.id,      'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Model.UserData.id,             'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Importador.UserData.id,        'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.CodAduana.UserData.id,         'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.UnitPrice.UserData.id,         'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.UnitPriceSource.UserData.id,   'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.QtdVendida.UserData.id,        'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.QtdUso.UserData.id,            'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.QtdEstoque.UserData.id,        'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.QtdAnunciada.UserData.id,      'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.QtdLacrada.UserData.id,        'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.QtdApreendida.UserData.id,     'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.QtdRetida.UserData.id,         'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Situation.UserData.id,         'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.ViolationType.UserData.id,     'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Corrigible.UserData.id,        'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Lacre.UserData.id,             'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.PLAI.UserData.id,              'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Note.UserData.id,              'generation', 1, 'style', struct('borderColor', '#AEBBC2')) ...
                });
            catch
            end
        end

        %-----------------------------------------------------------------%
        function initialValues(app)
            app.HomologationPanel.BorderColor = '#c9d9ec';
            app.Type.Items = categories(app.projectData.inspectedProducts.("Tipo"));
            app.Subtype.Items = {};
            app.Situation.Items = categories(app.projectData.inspectedProducts.("Situação"));
            app.ViolationType.Items = categories(app.projectData.inspectedProducts.("Infração"));
            app.Corrigible.Items = categories(app.projectData.inspectedProducts.("Sanável?"));

            currentProductIdx = app.inputArgs.selectedProductIdx;
            updateForm(app, currentProductIdx)
        end

        %-----------------------------------------------------------------%
        function updateForm(app, currentProductIdx)
            updateHomologationTitle(app, currentProductIdx)

            numProducts = height(app.projectData.inspectedProducts);
            app.ProductPosition.Text = sprintf('%d de %d', currentProductIdx, numProducts);
            set([app.ProductNext, app.ProductPrevious], 'Enable', numProducts > 1)

            app.Type.Value = char(app.projectData.inspectedProducts.("Tipo")(currentProductIdx));
            updateTypeSubtypeMapping(app, app.Type.Value, app.projectData.inspectedProducts.("Subtipo"){currentProductIdx})

            app.Manufacturer.Value = app.projectData.inspectedProducts.("Fabricante"){currentProductIdx};
            app.Model.Value = app.projectData.inspectedProducts.("Modelo"){currentProductIdx};
            app.Importador.Value = app.projectData.inspectedProducts.("Importador"){currentProductIdx};
            app.CodAduana.Value = app.projectData.inspectedProducts.("Código aduaneiro"){currentProductIdx};

            app.RF.Value = app.projectData.inspectedProducts.("RF?")(currentProductIdx);
            app.InUse.Value = app.projectData.inspectedProducts.("Em uso?")(currentProductIdx);
            app.Interference.Value = app.projectData.inspectedProducts.("Interferência?")(currentProductIdx);

            app.UnitPrice.Value = app.projectData.inspectedProducts.("Valor Unit. (R$)")(currentProductIdx);
            app.UnitPriceSource.Value = app.projectData.inspectedProducts.("Fonte do valor"){currentProductIdx};
            app.QtdUso.Value = double(app.projectData.inspectedProducts.("Qtd. uso")(currentProductIdx));
            app.QtdVendida.Value = double(app.projectData.inspectedProducts.("Qtd. vendida")(currentProductIdx));
            app.QtdEstoque.Value = double(app.projectData.inspectedProducts.("Qtd. estoque/aduana")(currentProductIdx));
            app.QtdAnunciada.Value = double(app.projectData.inspectedProducts.("Qtd. anunciada")(currentProductIdx));

            app.QtdLacrada.Value = double(app.projectData.inspectedProducts.("Qtd. lacradas")(currentProductIdx));
            app.QtdApreendida.Value = double(app.projectData.inspectedProducts.("Qtd. apreendidas")(currentProductIdx));
            app.QtdRetida.Value = double(app.projectData.inspectedProducts.("Qtd. retidas (RFB)")(currentProductIdx));

            app.Lacre.Value = app.projectData.inspectedProducts.("Lacre"){currentProductIdx};
            app.PLAI.Value = app.projectData.inspectedProducts.("PLAI"){currentProductIdx};

            app.Situation.Value = char(app.projectData.inspectedProducts.("Situação")(currentProductIdx));
            app.ViolationType.Value = char(app.projectData.inspectedProducts.("Infração")(currentProductIdx));
            app.Corrigible.Value = char(app.projectData.inspectedProducts.("Sanável?")(currentProductIdx));
            app.Note.Value = app.projectData.inspectedProducts.("Informações adicionais"){currentProductIdx};
        end

        %-----------------------------------------------------------------%
        function updateHomologationTitle(app, currentProductIdx)
            homologation = app.projectData.inspectedProducts.("Homologação"){currentProductIdx};
            if strcmp(homologation, '-')
                homologation = 'Produto não homologado';
            end

            [~, ruleViolationMatrix] = validateInspectedProducts(app.projectData);
            warningLink = '';
            if any(ruleViolationMatrix(currentProductIdx, :))
                warningLink = ['&nbsp;' createWarningLink(app)];
            end

            app.Homologation.Text = sprintf([ ...
                '<section>' ...
                    '<table style="width: 100%%;">' ...
                        '<tbody>' ...
                            '<tr>' ...
                                '<td><font style="font-size: 16px;"><b>%s</b>%s</font><br>Caracterização do produto e levantamento de informações fiscalizatórias.</td>' ...
                                '<td style="width: 76px;"><section style="border: 1px solid #C3D9EC; border-radius: 5px; padding: 5px; margin-right: 2px; background-color: #DCEBFA; color: #245A7A">Em edição</section></td>' ...
                            '</tr>' ...
                        '</tbody>' ...
                    '</table>' ...
                '</section>' ...
            ], homologation, warningLink);
        end

        %-----------------------------------------------------------------%
        function htmlLink = createWarningLink(app)
            if isempty(app.AppHandleNameInBase) || ~evalin('base', sprintf('exist("%s", "var") && isa(%s, "%s") && isvalid(%s)', app.AppHandleNameInBase, app.AppHandleNameInBase, class(app), app.AppHandleNameInBase))
                app.AppHandleNameInBase = ui.Table.exportAppHandleToBaseWorkspace(app);
            end

            htmlLink = ui.TextView.createHTMLLink('customImage', app.AppHandleNameInBase, 'onMobileProductWarningRequested', '1', 'warning-20px-red.svg', 20, 20, app.mainApp.General);
            htmlLink = sprintf('<span title="Ver pendências do registro">%s</span>', htmlLink);
        end

        %-----------------------------------------------------------------%
        function showInspectedProductWarnings(app)
            currentProductIdx = app.inputArgs.selectedProductIdx;

            [~, ruleViolationMatrix] = validateInspectedProducts(app.projectData);
            if ~any(ruleViolationMatrix(currentProductIdx, :))
                return
            end

            html = util.HtmlTextGenerator.createInspectedProductWarnings(ruleViolationMatrix(currentProductIdx, :));

            sendEventToHTMLSource(app.mainApp.jsBackDoor, 'textHighlight', struct( ...
                'dataTag',  'productWarnings', ...
                'imageUrl', 'warning-20px-red.svg', ...
                'title',    'Registro com pendências', ...
                'width',    85, ...
                'height',   50, ...
                'html',     html ...
            ));
        end

        %-----------------------------------------------------------------%
        function homologation = getHomolotation(app)
            currentProductIdx = app.inputArgs.selectedProductIdx;
            homologation = app.projectData.inspectedProducts.("Homologação"){currentProductIdx};
        end

        %-----------------------------------------------------------------%
        function updateTypeSubtypeMapping(app, type, subtype)
            [subtype, subtypeList] = checkTypeSubtypeProductsMapping(app.projectData, type, subtype);
            subtypeEnableList = ~isequal(subtypeList, {'-'});

            set(app.Subtype, 'Enable', subtypeEnableList, 'Items', subtypeList, 'Value', subtype)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context, selectedProductIdx)
            
            try
                appEngine.boot(app, app.Role, mainApp, callingApp)

                app.inputArgs = struct('context', context, 'selectedProductIdx', selectedProductIdx);
                applyJSCustomizations(app)
                initialValues(app)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end            
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            delete(app)
            
        end

        % Value changed function: CodAduana, Corrigible, Importador, 
        % ...and 21 other components
        function onParameterValueChanged(app, event)
            
            % Trata dados textuais...
            srcClass = class(event.Source);
            switch srcClass
                case 'matlab.ui.control.EditField'
                    event.Source.Value = strtrim(event.Source.Value);
                case 'matlab.ui.control.TextArea'
                    event.Source.Value = textFormatGUI.cellstr2TextField(event.Source.Value, '\n');
            end

            optionalNotes = app.Note.Value;
            if iscellstr(optionalNotes)
                optionalNotes = strjoin(optionalNotes, '\n');
            end

            switch event.Source
                case app.Type
                    % Verifica se edição ocorreu no campo "TIPO", o que demanda
                    % atualização do "SUBTIPO".
                    updateTypeSubtypeMapping(app, app.Type.Value, app.Subtype.Value)

                case {app.Manufacturer, app.Model}
                    % Verifica se edição ocorreu em produto NÃO homologado, o que
                    % demanda recálculo do hash.
                    if strcmp(getHomolotation(app), '-')
                        newNonCertificateHash = Hash.base64encode(strjoin({'-', app.Manufacturer.Value, app.Model.Value}, ' - '));
        
                        if ismember(newNonCertificateHash, app.projectData.inspectedProducts.("Hash"))
                            currentProductIdx = app.inputArgs.selectedProductIdx;
                            updateForm(app, currentProductIdx)

                            ui.Dialog(app.UIFigure, 'warning', model.ProjectBase.WARNING_ENTRYEXIST.PRODUCTS);
                            return
                        end
                    end
            end

            subtype = app.Subtype.Value;
            if isempty(app.Subtype.Value)
                subtype = '';
            end
            
            productData = {
                'Homologação',           getHomolotation(app);
                'Importador',            app.Importador.Value;
                'Código aduaneiro',      app.CodAduana.Value;
                'Tipo',                  app.Type.Value;
                'Subtipo',               subtype;
                'Fabricante',            app.Manufacturer.Value;
                'Modelo',                app.Model.Value;
                'RF?',                   app.RF.Value;
                'Em uso?',               app.InUse.Value;
                'Interferência?',        app.Interference.Value;
                'Valor Unit. (R$)',      app.UnitPrice.Value;
                'Fonte do valor',        app.UnitPriceSource.Value;
                'Qtd. uso',              app.QtdUso.Value;
                'Qtd. vendida',          app.QtdVendida.Value;
                'Qtd. estoque/aduana',   app.QtdEstoque.Value;
                'Qtd. anunciada',        app.QtdAnunciada.Value;
                'Qtd. lacradas',         app.QtdLacrada.Value;
                'Qtd. apreendidas',      app.QtdApreendida.Value;
                'Qtd. retidas (RFB)',    app.QtdRetida.Value;
                'Lacre',                 app.Lacre.Value;
                'PLAI',                  app.PLAI.Value;
                'Situação',              app.Situation.Value;
                'Infração',              app.ViolationType.Value;
                'Sanável?',              app.Corrigible.Value;
                'Informações adicionais', optionalNotes
            };

            currentProductIdx = app.inputArgs.selectedProductIdx;
            updateInspectedProducts(app.projectData, 'edit', currentProductIdx, productData(:, 1), productData(:, 2)')
            updateHomologationTitle(app, currentProductIdx)
            ipcMainMatlabCallsHandler(app.mainApp, app, 'onInspectedProductEdited', "PRODUCTS", currentProductIdx)
            
        end

        % Image clicked function: ProductNext, ProductPrevious
        function onProductsArrowButtonClicked(app, event)
            
            currentProductIdx = app.inputArgs.selectedProductIdx;
            numProducts = height(app.projectData.inspectedProducts);

            switch event.Source
                case app.ProductPrevious
                    newProductIdx = currentProductIdx - 1;
                case app.ProductNext
                    newProductIdx = currentProductIdx + 1;
            end

            if newProductIdx < 1
                newProductIdx = numProducts;
            elseif newProductIdx > numProducts
                newProductIdx = 1;
            end

            app.inputArgs.selectedProductIdx = newProductIdx;
            updateForm(app, newProductIdx)
            focus(app.UIFigure)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HomologationPanel = uipanel(app.GridLayout);
            app.HomologationGrid = uigridlayout(app.HomologationPanel);
            app.HomologationIcon = uiimage(app.HomologationGrid);
            app.HomologationIcon.ImageSource = 'package.svg';
            app.Homologation = uilabel(app.HomologationGrid);
            app.Homologation.Interpreter = 'html';
            app.Homologation.Text = '';
            app.ProductPosition = uilabel(app.HomologationGrid);
            app.ProductPosition.Text = '';
            app.ProductPrevious = uiimage(app.HomologationGrid);
            app.ProductPrevious.ImageClickedFcn = createCallbackFcn(app, @onProductsArrowButtonClicked, true);
            app.ProductPrevious.ImageSource = 'chevron-left.svg';
            app.ProductNext = uiimage(app.HomologationGrid);
            app.ProductNext.ImageClickedFcn = createCallbackFcn(app, @onProductsArrowButtonClicked, true);
            app.ProductNext.ImageSource = 'chevron-right.svg';
            app.ProductStatusGrid = uigridlayout(app.GridLayout);
            app.TypeLabel = uilabel(app.ProductStatusGrid);
            app.TypeLabel.Text = 'Tipo:';
            app.Type = uidropdown(app.ProductStatusGrid);
            app.Type.Items = {''};
            app.Type.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.Type.Value = '';
            app.SubtypeLabel = uilabel(app.ProductStatusGrid);
            app.SubtypeLabel.Text = 'Subtipo:';
            app.Subtype = uidropdown(app.ProductStatusGrid);
            app.Subtype.Items = {''};
            app.Subtype.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.Subtype.Enable = 'off';
            app.Subtype.Value = '';
            app.ManufacturerLabel = uilabel(app.ProductStatusGrid);
            app.ManufacturerLabel.Text = 'Fabricante:';
            app.Manufacturer = uieditfield(app.ProductStatusGrid, 'text');
            app.Manufacturer.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.ModelLabel = uilabel(app.ProductStatusGrid);
            app.ModelLabel.Text = 'Modelo:';
            app.Model = uieditfield(app.ProductStatusGrid, 'text');
            app.Model.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.ImportadorLabel = uilabel(app.ProductStatusGrid);
            app.ImportadorLabel.Text = 'Importador:';
            app.Importador = uieditfield(app.ProductStatusGrid, 'text');
            app.Importador.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.CodAduanaLabel = uilabel(app.ProductStatusGrid);
            app.CodAduanaLabel.Text = 'Código aduaneiro:';
            app.CodAduana = uieditfield(app.ProductStatusGrid, 'text');
            app.CodAduana.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.EvidenceLabel = uilabel(app.ProductStatusGrid);
            app.EvidenceLabel.Text = 'Evidências:';
            app.RF = uicheckbox(app.ProductStatusGrid);
            app.RF.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.RF.Text = 'Produto usa RF';
            app.InUse = uicheckbox(app.ProductStatusGrid);
            app.InUse.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.InUse.Text = 'Produto foi UTILIZADO';
            app.Interference = uicheckbox(app.ProductStatusGrid);
            app.Interference.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.Interference.Text = 'Houve INTERFERÊNCIA decorrente do uso';
            app.UnitPriceLabel = uilabel(app.ProductStatusGrid);
            app.UnitPriceLabel.Text = 'Valor unitário (R$):';
            app.UnitPrice = uieditfield(app.ProductStatusGrid, 'numeric');
            app.UnitPrice.Limits = [0 Inf];
            app.UnitPrice.ValueDisplayFormat = '%.2f';
            app.UnitPrice.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.UnitPriceSourceLabel = uilabel(app.ProductStatusGrid);
            app.UnitPriceSourceLabel.Text = 'Fonte (nota fiscal, mostruário etc):';
            app.UnitPriceSource = uieditfield(app.ProductStatusGrid, 'text');
            app.UnitPriceSource.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.QtdVendidaLabel = uilabel(app.ProductStatusGrid);
            app.QtdVendidaLabel.Text = 'Qtd. vendida:';
            app.QtdVendida = uieditfield(app.ProductStatusGrid, 'numeric');
            app.QtdVendida.Limits = [0 Inf];
            app.QtdVendida.RoundFractionalValues = 'on';
            app.QtdVendida.ValueDisplayFormat = '%.0f';
            app.QtdVendida.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.QtdUsoLabel = uilabel(app.ProductStatusGrid);
            app.QtdUsoLabel.Text = 'Qtd. em uso:';
            app.QtdUso = uieditfield(app.ProductStatusGrid, 'numeric');
            app.QtdUso.Limits = [0 Inf];
            app.QtdUso.RoundFractionalValues = 'on';
            app.QtdUso.ValueDisplayFormat = '%.0f';
            app.QtdUso.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.QtdEstoqueLabel = uilabel(app.ProductStatusGrid);
            app.QtdEstoqueLabel.Text = 'Qtd. estoque:';
            app.QtdEstoque = uieditfield(app.ProductStatusGrid, 'numeric');
            app.QtdEstoque.Limits = [0 Inf];
            app.QtdEstoque.RoundFractionalValues = 'on';
            app.QtdEstoque.ValueDisplayFormat = '%.0f';
            app.QtdEstoque.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.QtdAnunciadaLabel = uilabel(app.ProductStatusGrid);
            app.QtdAnunciadaLabel.Text = 'Qtd. anunciada:';
            app.QtdAnunciada = uieditfield(app.ProductStatusGrid, 'numeric');
            app.QtdAnunciada.Limits = [0 Inf];
            app.QtdAnunciada.RoundFractionalValues = 'on';
            app.QtdAnunciada.ValueDisplayFormat = '%.0f';
            app.QtdAnunciada.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.QtdLacradaLabel = uilabel(app.ProductStatusGrid);
            app.QtdLacradaLabel.Text = 'Qtd. lacrada:';
            app.QtdLacrada = uieditfield(app.ProductStatusGrid, 'numeric');
            app.QtdLacrada.Limits = [0 Inf];
            app.QtdLacrada.RoundFractionalValues = 'on';
            app.QtdLacrada.ValueDisplayFormat = '%.0f';
            app.QtdLacrada.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.QtdApreendidaLabel = uilabel(app.ProductStatusGrid);
            app.QtdApreendidaLabel.Text = 'Qtd. apreendida:';
            app.QtdApreendida = uieditfield(app.ProductStatusGrid, 'numeric');
            app.QtdApreendida.Limits = [0 Inf];
            app.QtdApreendida.RoundFractionalValues = 'on';
            app.QtdApreendida.ValueDisplayFormat = '%.0f';
            app.QtdApreendida.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.QtdRetidaLabel = uilabel(app.ProductStatusGrid);
            app.QtdRetidaLabel.Text = 'Qtd. retida (RFB):';
            app.QtdRetida = uieditfield(app.ProductStatusGrid, 'numeric');
            app.QtdRetida.Limits = [0 Inf];
            app.QtdRetida.RoundFractionalValues = 'on';
            app.QtdRetida.ValueDisplayFormat = '%.0f';
            app.QtdRetida.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.SituationLabel = uilabel(app.ProductStatusGrid);
            app.SituationLabel.Text = 'Situação:';
            app.Situation = uidropdown(app.ProductStatusGrid);
            app.Situation.Items = {};
            app.Situation.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.Situation.Value = {};
            app.CorrigibleLabel = uilabel(app.ProductStatusGrid);
            app.CorrigibleLabel.Text = 'Sanável?';
            app.Corrigible = uidropdown(app.ProductStatusGrid);
            app.Corrigible.Items = {};
            app.Corrigible.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.Corrigible.Value = {};
            app.ViolationTypeLabel = uilabel(app.ProductStatusGrid);
            app.ViolationTypeLabel.Text = 'Infração:';
            app.ViolationType = uidropdown(app.ProductStatusGrid);
            app.ViolationType.Items = {};
            app.ViolationType.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.ViolationType.Value = {};
            app.PLAILabel = uilabel(app.ProductStatusGrid);
            app.PLAILabel.Text = 'PLAI:';
            app.PLAI = uieditfield(app.ProductStatusGrid, 'text');
            app.PLAI.CharacterLimits = [0 20];
            app.PLAI.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.LacreLabel = uilabel(app.ProductStatusGrid);
            app.LacreLabel.Text = 'Lacre:';
            app.Lacre = uieditfield(app.ProductStatusGrid, 'text');
            app.Lacre.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            app.NoteLabel = uilabel(app.ProductStatusGrid);
            app.NoteLabel.Text = 'Informações adicionais:';
            app.Note = uitextarea(app.ProductStatusGrid);
            app.Note.ValueChangedFcn = createCallbackFcn(app, @onParameterValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mobileProductInfo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end