classdef mobileSearchAddSelectedToBucket < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        Button             matlab.ui.control.Button
        Tree               matlab.ui.container.CheckBoxTree
        TreeLabel          matlab.ui.control.Label
        HomologationPanel  matlab.ui.container.Panel
        HomologationGrid   matlab.ui.container.GridLayout
        Homologation       matlab.ui.control.Label
        HomologationIcon   matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockAppMobile'
        alreadyIncludedIdxs = []
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
    end
    
    
    methods
        %-----------------------------------------------------------------%
        function ipcSecondaryMatlabCallsHandler(app, callingApp, eventName, varargin)
            try
                switch class(callingApp)
                    case {'mainApp.mobile', 'mainApp.mobile_exported'}
                        switch eventName
                            case 'updateForm'
                                schDetailedIdxs = varargin{1};
                                updateForm(app, schDetailedIdxs)

                            otherwise
                                error('auxApp:mobileSearchAddSelectedToBucket:UnexpectedCall', 'Unexpected call "%s"', eventName)
                        end

                    otherwise
                        error('auxApp:mobileSearchAddSelectedToBucket:UnexpectedCaller', 'Unexpected caller "%s"', class(callingApp))
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function applyJSCustomizations(app)
            appName = class(app);
            elToModify = {
                app.HomologationPanel;
                app.Tree
            };
            ui.CustomizationBase.getElementsDataTag(elToModify);
    
            try
                sendEventToHTMLSource(app.mainApp.jsBackDoor, 'initializeComponents', { ...
                    struct('appName', appName, 'dataTag', app.HomologationPanel.UserData.id, 'generation', 1, 'style', struct('borderRadius', '8px')), ...
                    struct('appName', appName, 'dataTag', app.Tree.UserData.id,              'generation', 1, 'style', struct('borderRadius', '8px', 'borderColor', '#AEBBC2')) ...
                });
            catch
            end
        end

        %-----------------------------------------------------------------%
        function initialValues(app, schDetailedIdxs)
            app.HomologationPanel.BorderColor = '#c9d9ec';
            updateForm(app, schDetailedIdxs)
        end

        %-----------------------------------------------------------------%
        function checkedIdxs = getCheckedIdxs(app)
            checkedIdxs = [];
            if ~isempty(app.Tree.CheckedNodes)
                checkedIdxs = [app.Tree.CheckedNodes.NodeData];
            end
        end

        %-----------------------------------------------------------------%
        function updateForm(app, schDetailedIdxs)
            % O formulário pode ser reaproveitado (popup já aberto), então
            % o estado anterior é descartado.
            delete(app.Tree.Children)
            app.Tree.Enable = 'on';
            app.Button.Enable = 'off';
            app.alreadyIncludedIdxs = [];

            homologation = app.mainApp.schData.detailed.("Homologação"){schDetailedIdxs(1)};
            app.Homologation.Text = sprintf([ ...
                '<font style="font-size: 16px;"><b>%s</b></font><p style="text-align: justify;">Este certificado está ' ...
                'associado a mais de um modelo ou nome comercial. Selecione abaixo os ' ...
                'registros que foram efetivamente inspecionados.</p>' ...
            ], homologation);

            checkedNodes = matlab.ui.container.TreeNode.empty;

            for ii = 1:numel(schDetailedIdxs)
                idx = schDetailedIdxs(ii);

                nodeText = sprintf( ...
                    '<br>%s<br>%s<br>%s<br>%s<br><br>', ...
                    app.mainApp.schData.detailed.("Solicitante"){idx}, ...
                    app.mainApp.schData.detailed.("Fabricante"){idx}, ...
                    app.mainApp.schData.detailed.("Modelo"){idx}, ...
                    app.mainApp.schData.detailed.("Nome Comercial"){idx} ...
                );

                treeNode = uitreenode(app.Tree, 'Text', nodeText, 'NodeData', idx);

                [~, productHash] = model.ProjectBase.initializeInspectedProduct('Homologado', app.mainApp.General, app.mainApp.schData.detailed, idx);    
                if ismember(productHash, app.mainApp.projectData.inspectedProducts.("Hash"))
                    checkedNodes(end+1) = treeNode;
                    app.alreadyIncludedIdxs(end+1) = idx;
                end
            end

            if ~isempty(checkedNodes)
                app.Tree.CheckedNodes = checkedNodes;
            end

            if numel(app.Tree.Children) == numel(checkedNodes)
                app.Tree.Enable = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context, schDetailedIdxs)
            
            try
                appEngine.boot(app, app.Role, mainApp, callingApp)
                
                addStyle(app.Tree, uistyle('Interpreter', 'html'))
                applyJSCustomizations(app)
                initialValues(app, schDetailedIdxs)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Callback function: Tree
        function onTreeCheckedNodesChanged(app, event)

            nodes = app.Tree.Children;
            checkedIdxs = getCheckedIdxs(app);

            % Itens já incluídos na lista de produtos inspecionados não 
            % podem ser desmarcados: se isso ocorreu, restaura-se a marcação.
            if ~all(ismember(app.alreadyIncludedIdxs, checkedIdxs))
                restoredIdxs = union(checkedIdxs, app.alreadyIncludedIdxs);
                app.Tree.CheckedNodes = nodes(ismember([nodes.NodeData], restoredIdxs));
                return
            end

            app.Button.Enable = ~isempty(setdiff(checkedIdxs, app.alreadyIncludedIdxs));

        end

        % Button pushed function: Button
        function onButtonPushed(app, event)

            newIdxs = setdiff(getCheckedIdxs(app), app.alreadyIncludedIdxs);
            if isempty(newIdxs)
                app.Button.Enable = 'off';
                return
            end

            ipcMainMatlabCallsHandler(app.mainApp, app, 'onAddSelectedToBucketRequest', newIdxs)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HomologationPanel = uipanel(app.GridLayout);
            app.HomologationGrid = uigridlayout(app.HomologationPanel);
            app.HomologationIcon = uiimage(app.HomologationGrid);
            app.HomologationIcon.ImageSource = 'package.svg';
            app.Homologation = uilabel(app.HomologationGrid);
            app.Homologation.Interpreter = 'html';
            app.Homologation.Text = '';
            app.TreeLabel = uilabel(app.GridLayout);
            app.TreeLabel.Interpreter = 'html';
            app.TreeLabel.Text = {'<b>Registros encontrados:</b>'; '<font style="font-size: 11px;">(Solicitante, Fabricante, Modelo e Nome Comercial)</font>'};
            app.Tree = uitree(app.GridLayout, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @onTreeCheckedNodesChanged, true);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @onButtonPushed, true);
            app.Button.Tag = 'OK';
            app.Button.Icon = 'add-16px-white.svg';
            app.Button.Enable = 'off';
            app.Button.Text = 'Adicionar modelos selecionados';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mobileSearchAddSelectedToBucket(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end