classdef mobileSearchFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        CurrentFilterPanel      matlab.ui.container.Panel
        CurrentFilterGrid       matlab.ui.container.GridLayout
        CurrentFilterColumn     matlab.ui.control.Label
        CurrentFilterText       matlab.ui.control.Label
        CurrentFilterStrategy   matlab.ui.control.Label
        CurrentFilterLabel      matlab.ui.control.Label
        CurrentFilterIcon       matlab.ui.control.Image
        SaveButton              matlab.ui.control.Button
        RefreshButton           matlab.ui.control.Button
        ColumnFilterPanel       matlab.ui.container.Panel
        ColumnFilterGrid        matlab.ui.container.GridLayout
        ColumnFilterList        matlab.ui.container.CheckBoxTree
        ColumnFilterDel         matlab.ui.control.Image
        ColumnFilterAdd         matlab.ui.control.Image
        Value2_TextFree         matlab.ui.control.EditField
        Value2_TextList         matlab.ui.control.DropDown
        Value2_Numeric          matlab.ui.control.NumericEditField
        Value2_Date             matlab.ui.control.DatePicker
        Operation2_List         matlab.ui.control.DropDown
        Operation2_LogicalGrid  matlab.ui.container.ButtonGroup
        Operation2_LogicalOr    matlab.ui.control.RadioButton
        Operation2_LogicalAnd   matlab.ui.control.RadioButton
        Value1_TextFree         matlab.ui.control.EditField
        Value1_TextList         matlab.ui.control.DropDown
        Value1_Numeric          matlab.ui.control.NumericEditField
        Value1_Date             matlab.ui.control.DatePicker
        Operation1_List         matlab.ui.control.DropDown
        SymbolicNameList        matlab.ui.control.DropDown
        ColumnFilterLabel       matlab.ui.control.Label
        FreeTextRadioGroup      matlab.ui.container.ButtonGroup
        FreeTextByMatch         matlab.ui.control.RadioButton
        FreeTextBySimilatiry    matlab.ui.control.RadioButton
        FreeTextLabel           matlab.ui.control.Label
        FilterStrategy          matlab.ui.control.DropDown
        FilterStrategyLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockAppMobile'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = true        
        mainApp
        callingApp
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        inputArgs
        draftSearch
        draftFilteringObj
    end


    methods
        %-----------------------------------------------------------------%
        function ipcSecondaryMatlabCallsHandler(app, callingApp, eventName, varargin)
            try
                switch class(callingApp)
                    case {'mainApp.mobile', 'mainApp.mobile_exported'}
                        switch eventName
                            case 'updateForm'
                                % ...

                            otherwise
                                error('auxApp:mobileSearchFilter:UnexpectedCall', 'Unexpected call "%s"', eventName)
                        end
    
                    otherwise
                        error('auxApp:mobileSearchFilter:UnexpectedCaller', 'Unexpected caller "%s"', class(callingApp))
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end
    end
    
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function applyJSCustomizations(app)
            appName = class(app);
            elToModify = {
                app.CurrentFilterPanel;
                app.FilterStrategy;
                app.FreeTextRadioGroup;
                app.ColumnFilterPanel;
                app.SymbolicNameList;
                app.Operation1_List;
                app.Value1_TextFree;
                app.Operation2_List;
                app.Value2_TextFree;
                app.ColumnFilterList;
                app.Value1_Date;     % Invisível na inicialização
                app.Value1_Numeric;  % Invisível na inicialização
                app.Value1_TextList; % Invisível na inicialização
                app.Value2_Date;     % Invisível na inicialização
                app.Value2_Numeric;  % Invisível na inicialização
                app.Value2_TextList  % Invisível na inicialização
            };
            ui.CustomizationBase.getElementsDataTag(elToModify);
    
            try
                sendEventToHTMLSource(app.mainApp.jsBackDoor, 'initializeComponents', { ...
                    struct('appName', appName, 'dataTag', app.CurrentFilterPanel.UserData.id, 'generation', 1, 'style', struct('borderRadius', '8px')), ...
                    struct('appName', appName, 'dataTag', app.FreeTextRadioGroup.UserData.id, 'generation', 1, 'style', struct('borderRadius', '8px')), ...
                    struct('appName', appName, 'dataTag', app.ColumnFilterPanel.UserData.id,  'generation', 1, 'style', struct('borderRadius', '8px')), ...
                    struct('appName', appName, 'dataTag', app.ColumnFilterList.UserData.id,   'generation', 1, 'style', struct('borderRadius', '8px', 'borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.FilterStrategy.UserData.id,     'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.SymbolicNameList.UserData.id,   'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Operation1_List.UserData.id,    'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Value1_TextFree.UserData.id,    'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Operation2_List.UserData.id,    'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Value2_TextFree.UserData.id,    'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Value1_Date.UserData.id,        'generation', 1, 'style', struct('borderColor', '#AEBBC2', 'backgroundColor', '#FFFFFF')), ...
                    struct('appName', appName, 'dataTag', app.Value1_Numeric.UserData.id,     'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Value1_TextList.UserData.id,    'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Value2_Date.UserData.id,        'generation', 1, 'style', struct('borderColor', '#AEBBC2', 'backgroundColor', '#FFFFFF')), ...
                    struct('appName', appName, 'dataTag', app.Value2_Numeric.UserData.id,     'generation', 1, 'style', struct('borderColor', '#AEBBC2')), ...
                    struct('appName', appName, 'dataTag', app.Value2_TextList.UserData.id,    'generation', 1, 'style', struct('borderColor', '#AEBBC2')) ...
                });
            catch
            end
        end

        %-----------------------------------------------------------------%
        function initialLayout(app)
            app.CurrentFilterPanel.BorderColor = '#c9d9ec';
            set([app.Value1_Date, app.Value2_Date], 'BackgroundColor', '#ffffff')
            set([app.FreeTextRadioGroup, app.ColumnFilterPanel], 'BorderColor', '#D5DDE1')
            addStyle(app.ColumnFilterList, uistyle('Interpreter', 'html'))

            % DROPDOWN "COLUNAS"
            columnRawNames = app.mainApp.schData.detailed.Properties.VariableNames;
            columnRawTypes = matlab.Compatibility.resolveTableVariableTypes(app.mainApp.schData.detailed);

            filterableMask = ~startsWith(columnRawNames, '_');
            columnRawNames = columnRawNames(filterableMask);
            columnRawTypes = columnRawTypes(filterableMask);

            [columnNames, sortedIdxs] = textAnalysis.sort(columnRawNames);
            columnTypes = columnRawTypes(sortedIdxs);
            
            pseudoClasses = tableFiltering.getPseudoClasses(columnTypes);
            symbolicNames = tableFiltering.mergedSymbolWithColumnNames(columnNames, columnTypes);
            
            % Atualiza componente dropdown com os nomes símbolicos das
            % colunas, além de armazenar em "UserData" detalhes sobre os
            % tipos de dados (nome, tipo e pseudo classe de cada coluna).
            app.SymbolicNameList.Items = [{''}; symbolicNames'];
            
            restartState(app)            
            app.SymbolicNameList.UserData.columnNames   = columnNames;
            app.SymbolicNameList.UserData.columnTypes   = columnTypes;
            app.SymbolicNameList.UserData.pseudoClasses = pseudoClasses;
        end

        %-----------------------------------------------------------------%
        function restartState(app)
            % O componente de INPUT do valor do filtro pode ser uidatepicker,
            % uieditfield (numeric/text) ou uidropdown, a depender da pseudo 
            % classe da coluna. Guarda-se um handle p/ o elemento ativo.
            app.Operation1_List.UserData.inputHandle = [];
            app.Operation2_List.UserData.inputHandle = [];
        end

        %-----------------------------------------------------------------%
        function refreshModule(app)
            app.draftSearch = struct( ...
                'type', app.mainApp.General.context.SEARCH.type, ...
                'mode', app.mainApp.General.context.SEARCH.mode ...
            );
            app.draftFilteringObj = tableFiltering;
            app.draftFilteringObj.filterRules = app.mainApp.filteringObj.filterRules;

            updateSearchContext(app)
            updateForm(app)
            updateButtonsState(app)
        end

        %-----------------------------------------------------------------%
        function updateSearchContext(app)
            searchType = app.mainApp.General.context.SEARCH.type;
            searchMode = app.mainApp.General.context.SEARCH.mode;

            switch searchType
                case 'FreeText'
                    searchStrategy = 'Texto livre';
                    app.CurrentFilterText.Visible = 'on';
                    app.CurrentFilterColumn.Visible = 'off';
                    app.CurrentFilterText.Layout.Column = [3 6];

                case 'ColumnFilter'
                    searchStrategy = 'Filtro por coluna';
                    app.CurrentFilterText.Visible = 'off';
                    app.CurrentFilterColumn.Visible = 'on';
                    app.CurrentFilterColumn.Layout.Column = [3 6];

                case 'FreeText+ColumnFilter'
                    searchStrategy = 'Texto livre +<br>Filtro por coluna';
                    set([app.CurrentFilterText, app.CurrentFilterColumn], 'Visible', 'on')
                    app.CurrentFilterText.Layout.Column = [3 4];
                    app.CurrentFilterColumn.Layout.Column = [5 6];
            end
            app.CurrentFilterStrategy.Text = sprintf('Estratégia:<br><font style="font-size:11px; color: #00213d;">%s</font>', searchStrategy);

            if contains(searchType, 'FreeText')
                switch searchMode
                    case 'tokens'
                        searchTextMode  = 'Similaridade';

                    otherwise % 'words'
                        searchTextMode  = 'Exata';
                end
                app.CurrentFilterText.Text = sprintf('Pesquisa textual:<br><font style="font-size:11px; color: #00213d;">%s</font>', searchTextMode);

            else
                app.CurrentFilterText.Text = '';
            end

            if contains(searchType, 'ColumnFilter')
                filterList = getFilterList(app.mainApp.filteringObj, 'SCH', 'on');
                
                if isempty(filterList)
                    app.CurrentFilterColumn.Text = 'Filtros por coluna:<br><font style="font-size:11px; color: #00213d;">Nenhum aplicado</font>';
                elseif isscalar(filterList)
                    app.CurrentFilterColumn.Text = 'Filtros por coluna:<br><font style="font-size:11px; color: #00213d;">Um aplicado</font>';
                else
                    app.CurrentFilterColumn.Text = sprintf('Filtros por coluna:<br><font style="font-size:11px; color: #00213d;">%d aplicados</font>', numel(filterList));
                end

            else
                app.CurrentFilterColumn.Text = '';
            end
        end

        %-----------------------------------------------------------------%
        function [modeChanged, columnFilterChanged] = getPendingChanges(app)
            searchSettings = app.mainApp.General.context.SEARCH;

            modeChanged = ~strcmp(app.draftSearch.type, searchSettings.type) || ~strcmp(app.draftSearch.mode, searchSettings.mode);
            columnFilterChanged = ~isequal(app.draftFilteringObj.filterRules, app.mainApp.filteringObj.filterRules);
        end

        %-----------------------------------------------------------------%
        function updateButtonsState(app)
            [modeChanged, columnFilterChanged] = getPendingChanges(app);
            set([app.RefreshButton, app.SaveButton], 'Enable', modeChanged || columnFilterChanged)
        end

        %-----------------------------------------------------------------%
        function updateForm(app)
            switch app.draftSearch.type
                case 'FreeText'
                    app.FilterStrategy.Value = 'Texto livre';
                case 'ColumnFilter'
                    app.FilterStrategy.Value = 'Filtro por coluna';
                otherwise % FreeText+ColumnFilter
                    app.FilterStrategy.Value = 'Texto livre + Filtro por coluna';
            end

            switch app.draftSearch.mode
                case 'tokens'
                    app.FreeTextBySimilatiry.Value = 1;
                otherwise % words
                    app.FreeTextByMatch.Value = 1;
            end

            set(app.FreeTextRadioGroup.Children, 'Enable', contains(app.FilterStrategy.Value, 'Texto livre'))
            set(app.ColumnFilterPanel,           'Enable', contains(app.FilterStrategy.Value, 'Filtro por coluna'))

            updateTree(app)
        end

        %-----------------------------------------------------------------%
        function updateTree(app)
            if ~isempty(app.ColumnFilterList.Children)
                delete(app.ColumnFilterList.Children)
                app.ColumnFilterDel.Visible = 'off';
            end

            filterList = getFilterList(app.draftFilteringObj, 'SCH');
            if ~isempty(filterList)
                checkedNodes = [];
    
                for ii = 1:numel(filterList)
                    childNodeText = replace(filterList{ii}, {' && ', ' || '}, {' && ...<br>', ' || ...<br>'});
                    childNode = uitreenode(app.ColumnFilterList, 'Text', childNodeText, 'NodeData', ii);
    
                    if app.draftFilteringObj.filterRules.Enable(ii)
                        checkedNodes = [checkedNodes, childNode];
                    end
                end
    
                app.ColumnFilterList.CheckedNodes = checkedNodes;
            end
        end

        %-----------------------------------------------------------------%
        function [columName, pseudoClass] = inspectColumnData(app)
            symbolicName = app.SymbolicNameList.Value;
            [~, symbolicIndex] = ismember(symbolicName, app.SymbolicNameList.Items);
            columnIndex = symbolicIndex-1;

            if columnIndex == 0
                columName   = '';
                pseudoClass = '';
            else
                columName   = app.SymbolicNameList.UserData.columnNames{columnIndex};
                pseudoClass = app.SymbolicNameList.UserData.pseudoClasses{columnIndex};
            end
        end

        %-----------------------------------------------------------------%
        function categories = getCategories(app, columnName)
            categories = {};
            categoriesIndex = find(strcmp({app.mainApp.schDataCategories.columnName}, columnName), 1);
            if ~isempty(categoriesIndex)
                categories = app.mainApp.schDataCategories(categoriesIndex).categories;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, callingApp, context)
            
            try
                appEngine.boot(app, app.Role, mainApp, callingApp)
                
                app.inputArgs = struct('context', context);
                applyJSCustomizations(app)
                initialLayout(app)
                refreshModule(app)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Callback function: FilterStrategy, FreeTextRadioGroup
        function onSearchModeChanged(app, event)
            
            switch event.Source
                case app.FilterStrategy
                    switch app.FilterStrategy.Value
                        case 'Texto livre'
                            app.draftSearch.type = 'FreeText';
                        case 'Filtro por coluna'
                            app.draftSearch.type = 'ColumnFilter';
                        otherwise % 'Texto livre + Filtro por coluna'
                            app.draftSearch.type = 'FreeText+ColumnFilter';
                    end

                case app.FreeTextRadioGroup            
                    switch app.FreeTextRadioGroup.SelectedObject
                        case app.FreeTextBySimilatiry
                            app.draftSearch.mode = 'tokens';
        
                        otherwise % app.FreeTextByMatch
                            app.draftSearch.mode = 'words';
                    end
            end

            updateForm(app)
            updateButtonsState(app)

        end

        % Value changed function: SymbolicNameList
        function onFilterColumnChanged(app, event)
            
            restartState(app)

            [columnName, pseudoClass] = inspectColumnData(app);
            app.SymbolicNameList.UserData.selected = struct('columnName', columnName, 'pseudoClass', pseudoClass);

            if isempty(pseudoClass)
                operations = {};
            else
                operations = tableFiltering.getFilterCapabilities(pseudoClass);
            end
            
            app.Operation1_List.Items = operations;
            set(app.Operation2_List, 'Items', [{''}, operations], 'Value', '')

            if ~isempty(operations)
                app.Operation1_List.Value = app.Operation1_List.Items{1};
                onFilterOperatorChanged(app, struct('Source', app.Operation1_List))
                onFilterOperatorChanged(app, struct('Source', app.Operation2_List))
            end

            app.ColumnFilterAdd.Enable = ~isempty(operations);

        end

        % Value changed function: Operation1_List, Operation2_List
        function onFilterOperatorChanged(app, event)
            
            switch event.Source
                case app.Operation1_List
                    valueHandles = [ ...
                        app.Value1_Date, ...
                        app.Value1_Numeric, ...
                        app.Value1_TextFree, ...
                        app.Value1_TextList ...
                    ];
                    
                case app.Operation2_List
                    valueHandles = [ ...
                        app.Value2_Date, ...
                        app.Value2_Numeric, ...
                        app.Value2_TextFree, ...
                        app.Value2_TextList ...
                    ];
            end
            tagHandles  = arrayfun(@(x) x.Tag, valueHandles, 'UniformOutput', false);

            columnName  = app.SymbolicNameList.UserData.selected.columnName;
            pseudoClass = app.SymbolicNameList.UserData.selected.pseudoClass;
            categories  = getCategories(app, columnName);

            switch pseudoClass
                case 'cellstr'
                    [~, tagIndex] = ismember('textFree', tagHandles);
                  % optionalArgs  = {'Value', ''};
                    optionalArgs  = {};

                case 'numeric'
                    [~, tagIndex] = ismember('numeric', tagHandles);
                    optionalArgs  = {};

                case 'datetime'
                    [~, tagIndex] = ismember('datePicker', tagHandles);
                    optionalArgs  = {};

                case 'categorical'
                    % Se a coluna tiver mais de 500 categorias, apresenta-se 
                    % como uieditfield (text) ao invés de dropdown.
                    if isempty(categories)
                        [~, tagIndex] = ismember('textFree', tagHandles);
                        optionalArgs  = {};
                    else
                        [~, tagIndex] = ismember('textList', tagHandles);
                        optionalArgs  = {'Items', [{''}; categories]};
                    end
            end

            event.Source.UserData.inputHandle = valueHandles(tagIndex);
            set(valueHandles(tagIndex), 'Visible', 1, optionalArgs{:})
            set(setdiff(valueHandles, valueHandles(tagIndex)), 'Visible', 0)
            
        end

        % Image clicked function: ColumnFilterAdd
        function onFilterAddImageClicked(app, event)
            
            columnName = app.SymbolicNameList.UserData.selected.columnName;            
            operators  = {app.Operation1_List.Value};
            values     = {app.Operation1_List.UserData.inputHandle.Value};
            connector  = app.Operation2_LogicalGrid.SelectedObject.Text;

            if ~isempty(app.Operation2_List.Value) && (~strcmp(app.Operation1_List.Value, app.Operation2_List.Value) || ~isequal(app.Operation1_List.UserData.inputHandle.Value, app.Operation2_List.UserData.inputHandle.Value))
                operators = [operators, {app.Operation2_List.Value}];
                values    = [values, {app.Operation2_List.UserData.inputHandle.Value}];
            end

            try
                addFilterRule(app.draftFilteringObj, columnName, operators, values, connector);
            catch ME
                ui.Dialog(app.UIFigure, 'warning', ME.message);
                return
            end
            updateTree(app)
            updateButtonsState(app)

        end

        % Image clicked function: ColumnFilterDel
        function onFilterDelColumnFilterDelClicked(app, event)
            
            selectedNodes = app.ColumnFilterList.SelectedNodes;

            if ~isempty(selectedNodes)
                removeFilterRule(app.draftFilteringObj, [selectedNodes.NodeData])
                updateTree(app)
                updateButtonsState(app)
            end

        end

        % Callback function: ColumnFilterList
        function onColumnFilterCheckedNodesChanged(app, event)

            checkedNodes = [];            
            if ~isempty(app.ColumnFilterList.CheckedNodes)
                checkedNodes = [app.ColumnFilterList.CheckedNodes.NodeData];
            end

            initialEnableArray = app.draftFilteringObj.filterRules.Enable;
            currentEnableArray = zeros(height(initialEnableArray), 1, 'logical');
            if ~isempty(checkedNodes)
                currentEnableArray(checkedNodes) = true;
            end

            if ~isequal(initialEnableArray, currentEnableArray)
                toogleFilterRule(app.draftFilteringObj, currentEnableArray)
                updateButtonsState(app)
            end
            
        end

        % Button pushed function: RefreshButton
        function onRefreshButtonPushed(app, event)
            
            refreshModule(app)

        end

        % Button pushed function: SaveButton
        function onSaveButtonPushed(app, event)
            
            [modeChanged, columnFilterChanged] = getPendingChanges(app);

            if modeChanged
                searchSettings = app.mainApp.General.context.SEARCH;
                searchSettings.type = app.draftSearch.type;
                searchSettings.mode = app.draftSearch.mode;

                switch searchSettings.mode
                    case 'tokens'
                        searchSettings.function = 'strcmp';
                    otherwise % words
                        searchSettings.function = 'contains';
                end

                app.mainApp.General.context.SEARCH   = searchSettings;
                app.mainApp.General_I.context.SEARCH = searchSettings;
                appEngine.util.generalSettingsSave(class.Constants.appName, app.mainApp.rootFolder, app.mainApp.General_I, app.mainApp.executionMode)
            end

            app.mainApp.filteringObj.filterRules = app.draftFilteringObj.filterRules;
            refreshModule(app)

            if modeChanged
                ipcMainMatlabCallsHandler(app.mainApp, app, 'onSearchModeChanged')
            elseif columnFilterChanged
                ipcMainMatlabCallsHandler(app.mainApp, app, 'onColumnFilterChanged')
            end

        end

        % Selection changed function: ColumnFilterList
        function ColumnFilterListSelectionChanged(app, event)
            
            app.ColumnFilterDel.Visible = ~isempty(app.ColumnFilterList.SelectedNodes);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FilterStrategyLabel = uilabel(app.GridLayout);
            app.FilterStrategyLabel.Text = 'Estratégia:';
            app.FilterStrategy = uidropdown(app.GridLayout);
            app.FilterStrategy.Items = {'Texto livre', 'Filtro por coluna', 'Texto livre + Filtro por coluna'};
            app.FilterStrategy.ValueChangedFcn = createCallbackFcn(app, @onSearchModeChanged, true);
            app.FilterStrategy.Value = 'Texto livre + Filtro por coluna';
            app.FreeTextLabel = uilabel(app.GridLayout);
            app.FreeTextLabel.Text = 'Pesquisa textual:';
            app.FreeTextRadioGroup = uibuttongroup(app.GridLayout);
            app.FreeTextRadioGroup.SelectionChangedFcn = createCallbackFcn(app, @onSearchModeChanged, true);
            app.FreeTextBySimilatiry = uiradiobutton(app.FreeTextRadioGroup);
            app.FreeTextBySimilatiry.Text = {'Similaridade'; '<font style="color: gray; font-size:11px">Apresenta sugestões conforme o texto é digitado</font>'};
            app.FreeTextBySimilatiry.Interpreter = 'html';
            app.FreeTextBySimilatiry.Value = true;
            app.FreeTextByMatch = uiradiobutton(app.FreeTextRadioGroup);
            app.FreeTextByMatch.Text = {'Texto Exato'; '<font style="color: gray; font-size:11px">Busca termos, separados por vírgulas, sem apresentar sugestões</font>'};
            app.FreeTextByMatch.Interpreter = 'html';
            app.ColumnFilterLabel = uilabel(app.GridLayout);
            app.ColumnFilterLabel.Text = 'Filtro por coluna:';
            app.ColumnFilterPanel = uipanel(app.GridLayout);
            app.ColumnFilterGrid = uigridlayout(app.ColumnFilterPanel);
            app.SymbolicNameList = uidropdown(app.ColumnFilterGrid);
            app.SymbolicNameList.Items = {};
            app.SymbolicNameList.ValueChangedFcn = createCallbackFcn(app, @onFilterColumnChanged, true);
            app.SymbolicNameList.Placeholder = 'Selecione uma coluna';
            app.SymbolicNameList.Value = {};
            app.Operation1_List = uidropdown(app.ColumnFilterGrid);
            app.Operation1_List.Items = {};
            app.Operation1_List.ValueChangedFcn = createCallbackFcn(app, @onFilterOperatorChanged, true);
            app.Operation1_List.Placeholder = 'Selecione';
            app.Operation1_List.Value = {};
            app.Value1_Date = uidatepicker(app.ColumnFilterGrid);
            app.Value1_Date.Editable = 'off';
            app.Value1_Date.Tag = 'datePicker';
            app.Value1_Date.Visible = 'off';
            app.Value1_Date.Placeholder = 'Informe a data';
            app.Value1_Numeric = uieditfield(app.ColumnFilterGrid, 'numeric');
            app.Value1_Numeric.Tag = 'numeric';
            app.Value1_Numeric.Visible = 'off';
            app.Value1_Numeric.Placeholder = 'Informe o valor';
            app.Value1_Numeric.Value = 1;
            app.Value1_TextList = uidropdown(app.ColumnFilterGrid);
            app.Value1_TextList.Items = {''};
            app.Value1_TextList.Editable = 'on';
            app.Value1_TextList.Tag = 'textList';
            app.Value1_TextList.Visible = 'off';
            app.Value1_TextList.Placeholder = 'Informe o valor';
            app.Value1_TextList.Value = '';
            app.Value1_TextFree = uieditfield(app.ColumnFilterGrid, 'text');
            app.Value1_TextFree.Tag = 'textFree';
            app.Value1_TextFree.Placeholder = 'Informe o valor';
            app.Operation2_LogicalGrid = uibuttongroup(app.ColumnFilterGrid);
            app.Operation2_LogicalAnd = uiradiobutton(app.Operation2_LogicalGrid);
            app.Operation2_LogicalAnd.Text = 'E';
            app.Operation2_LogicalAnd.Value = true;
            app.Operation2_LogicalOr = uiradiobutton(app.Operation2_LogicalGrid);
            app.Operation2_LogicalOr.Text = 'Ou';
            app.Operation2_List = uidropdown(app.ColumnFilterGrid);
            app.Operation2_List.Items = {};
            app.Operation2_List.ValueChangedFcn = createCallbackFcn(app, @onFilterOperatorChanged, true);
            app.Operation2_List.Value = {};
            app.Value2_Date = uidatepicker(app.ColumnFilterGrid);
            app.Value2_Date.Editable = 'off';
            app.Value2_Date.Tag = 'datePicker';
            app.Value2_Date.Visible = 'off';
            app.Value2_Numeric = uieditfield(app.ColumnFilterGrid, 'numeric');
            app.Value2_Numeric.Tag = 'numeric';
            app.Value2_Numeric.Visible = 'off';
            app.Value2_Numeric.Value = 1;
            app.Value2_TextList = uidropdown(app.ColumnFilterGrid);
            app.Value2_TextList.Items = {''};
            app.Value2_TextList.Editable = 'on';
            app.Value2_TextList.Tag = 'textList';
            app.Value2_TextList.Visible = 'off';
            app.Value2_TextList.Value = '';
            app.Value2_TextFree = uieditfield(app.ColumnFilterGrid, 'text');
            app.Value2_TextFree.Tag = 'textFree';
            app.ColumnFilterAdd = uiimage(app.ColumnFilterGrid);
            app.ColumnFilterAdd.ImageClickedFcn = createCallbackFcn(app, @onFilterAddImageClicked, true);
            app.ColumnFilterAdd.Enable = 'off';
            app.ColumnFilterAdd.ImageSource = 'Add_16.png';
            app.ColumnFilterDel = uiimage(app.ColumnFilterGrid);
            app.ColumnFilterDel.ImageClickedFcn = createCallbackFcn(app, @onFilterDelColumnFilterDelClicked, true);
            app.ColumnFilterDel.Visible = 'off';
            app.ColumnFilterDel.ImageSource = 'close-16px-red.svg';
            app.ColumnFilterList = uitree(app.ColumnFilterGrid, 'checkbox');
            app.ColumnFilterList.SelectionChangedFcn = createCallbackFcn(app, @ColumnFilterListSelectionChanged, true);
            % Assign Checked Nodes
            app.ColumnFilterList.CheckedNodesChangedFcn = createCallbackFcn(app, @onColumnFilterCheckedNodesChanged, true);
            app.RefreshButton = uibutton(app.GridLayout, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @onRefreshButtonPushed, true);
            app.RefreshButton.Icon = 'discard.svg';
            app.RefreshButton.Enable = 'off';
            app.RefreshButton.Text = 'Limpar alterações';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @onSaveButtonPushed, true);
            app.SaveButton.Icon = 'save-white.svg';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Salvar alterações';
            app.CurrentFilterPanel = uipanel(app.GridLayout);
            app.CurrentFilterGrid = uigridlayout(app.CurrentFilterPanel);
            app.CurrentFilterIcon = uiimage(app.CurrentFilterGrid);
            app.CurrentFilterIcon.ImageSource = 'filter-filled.svg';
            app.CurrentFilterLabel = uilabel(app.CurrentFilterGrid);
            app.CurrentFilterLabel.Text = 'Especificação do filtro atual';
            app.CurrentFilterStrategy = uilabel(app.CurrentFilterGrid);
            app.CurrentFilterStrategy.Interpreter = 'html';
            app.CurrentFilterStrategy.Text = 'Estratégia:<br><font style="font-size:11px; color: #00213d;">Texto livre +<br>Filtro por coluna</font>';
            app.CurrentFilterText = uilabel(app.CurrentFilterGrid);
            app.CurrentFilterText.Interpreter = 'html';
            app.CurrentFilterText.Text = 'Pesquisa textual:<br><font style="font-size:11px; color: #00213d;">Similaridade</font>';
            app.CurrentFilterColumn = uilabel(app.CurrentFilterGrid);
            app.CurrentFilterColumn.Interpreter = 'html';
            app.CurrentFilterColumn.Text = 'Filtros por coluna:<br><font style="font-size:11px; color: #00213d;">Nenhum aplicado</font>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mobileSearchFilter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end