classdef winProducts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        DockModule                  matlab.ui.container.GridLayout
        dockModule_Undock           matlab.ui.control.Image
        dockModule_Close            matlab.ui.control.Image
        Document                    matlab.ui.container.GridLayout
        report_Table                matlab.ui.control.Table
        report_nRows                matlab.ui.control.Label
        report_ViewType             matlab.ui.container.ButtonGroup
        AduanaButton                matlab.ui.control.RadioButton
        FornecedorUsurioButton      matlab.ui.control.RadioButton
        TabGroup                    matlab.ui.container.TabGroup
        PRODUTOTab                  matlab.ui.container.Tab
        report_Tab1Grid             matlab.ui.container.GridLayout
        report_ProjectLabel_2       matlab.ui.control.Label
        report_ProductInfo          matlab.ui.control.Label
        report_ProductInfoImage     matlab.ui.control.Image
        PROJETOTab                  matlab.ui.container.Tab
        Tab4Grid                    matlab.ui.container.GridLayout
        report_EntityPanel          matlab.ui.container.Panel
        report_EntityGrid           matlab.ui.container.GridLayout
        report_Entity               matlab.ui.control.EditField
        report_EntityLabel          matlab.ui.control.Label
        report_EntityID             matlab.ui.control.EditField
        report_EntityCheck          matlab.ui.control.Image
        report_EntityIDLabel        matlab.ui.control.Label
        report_EntityType           matlab.ui.control.DropDown
        report_EntityTypeLabel      matlab.ui.control.Label
        report_EntityPanelLabel     matlab.ui.control.Label
        report_ProjectName          matlab.ui.control.TextArea
        report_ProjectSave          matlab.ui.control.Image
        report_ProjectOpen          matlab.ui.control.Image
        report_ProjectNew           matlab.ui.control.Image
        report_ProjectLabel         matlab.ui.control.Label
        reportPanel                 matlab.ui.container.Panel
        reportGrid                  matlab.ui.container.GridLayout
        reportVersion               matlab.ui.control.DropDown
        reportVersionLabel          matlab.ui.control.Label
        reportModelName             matlab.ui.control.DropDown
        reportModelNameLabel        matlab.ui.control.Label
        reportLabel                 matlab.ui.control.Label
        eFiscalizaPanel             matlab.ui.container.Panel
        eFiscalizaGrid              matlab.ui.container.GridLayout
        reportIssue                 matlab.ui.control.NumericEditField
        reportIssueLabel            matlab.ui.control.Label
        reportUnit                  matlab.ui.control.DropDown
        reportUnitLabel             matlab.ui.control.Label
        reportSystem                matlab.ui.control.DropDown
        reportSystemLabel           matlab.ui.control.Label
        eFiscalizaLabel             matlab.ui.control.Label
        Toolbar                     matlab.ui.container.GridLayout
        report_EditProduct          matlab.ui.control.Image
        Image5                      matlab.ui.control.Image
        report_FiscalizaUpdate      matlab.ui.control.Image
        report_ReportGeneration     matlab.ui.control.Image
        report_leftPanelVisibility  matlab.ui.control.Image
        ContextMenu                 matlab.ui.container.ContextMenu
        EditarMenu                  matlab.ui.container.Menu
        DeletePoint                 matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false

        mainApp
        
        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj
        jsBackDoor

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog
        popupContainer
        
        %-----------------------------------------------------------------%
        % ESPECIFICIDADES AUXAPP.WINEXTERNALREQUEST
        %-----------------------------------------------------------------%
        projectData
        measData
        measTable

        % Handle do eixo e propriedade que armazena os limites automáticos
        UIAxes
        restoreView = struct('ID', {}, 'xLim', {}, 'yLim', {}, 'cLim', {})        
    end


    methods
        %-----------------------------------------------------------------%
        % IPC: COMUNICAÇÃO ENTRE PROCESSOS
        %-----------------------------------------------------------------%
        function ipcSecundaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        startup_Controller(app)

                    case 'customForm'
                        switch event.HTMLEventData.uuid
                            case 'eFiscalizaSignInPage'
                                report_uploadInfoController(app.mainApp, event.HTMLEventData, 'uploadDocument')

                            otherwise
                                error('UnexpectedEvent')
                        end

                    case 'auxApp.winExternalRequest.TreePoints'
                        DeleteSelectedPoint(app, struct('ContextObject', app.TreePoints))

                    otherwise
                        error('UnexpectedEvent')
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function ipcSecundaryMatlabCallsHandler(app, callingApp, operationType, varargin)
            try
                switch class(callingApp)
                    case {'winMonitorRNI', 'winMonitorRNI_exported'}
                        switch operationType
                            case {'FileListChanged:Add', ...
                                  'FileListChanged:Del', ...
                                  'FileListChanged:Unmerge', ...
                                  'FileListChanged:Merge'}
                                app.measData = app.mainApp.measData;
                                TreeFileLocationBuilding(app)

                                if ~isempty(app.measData)
                                    Analysis(app)
                                end

                            case 'ExternalRequest:AnalysisParameterChanged'
                                app.UITable.ColumnName{4} = sprintf('Qtd.|> %.0f V/m', app.mainApp.General.ExternalRequest.FieldValue);
                                updateAnalysis(app.projectData, app.measData, app.mainApp.General, operationType);
                                Analysis(app)

                            case 'ExternalRequest:AxesParameterChanged'
                                if ~isequal(app.UIAxes.Basemap, app.mainApp.General.Plot.GeographicAxes.Basemap)
                                    app.UIAxes.Basemap = app.mainApp.General.Plot.GeographicAxes.Basemap;

                                    switch app.mainApp.General.Plot.GeographicAxes.Basemap
                                        case {'darkwater', 'none'}
                                            app.UIAxes.Grid = 'on';
                                        otherwise
                                            app.UIAxes.Grid = 'off';
                                    end
                                end

                                plot.axes.Colormap(app.UIAxes, app.mainApp.General.Plot.GeographicAxes.Colormap)
                                plot.axes.Colorbar(app.UIAxes, app.mainApp.General.Plot.GeographicAxes.Colorbar)

                            case 'ExternalRequest:PlotParameterChanged'
                                Analysis(app)

                            otherwise
                                error('UnexpectedCall')
                        end
    
                    otherwise
                        error('UnexpectedCall')
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        % INICIALIZAÇÃO
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor = uihtml(app.UIFigure, "HTMLSource",           appUtil.jsBackDoorHTMLSource(),                 ...
                                                  "HTMLEventReceivedFcn", @(~, evt)ipcSecundaryJSEventsHandler(app, evt), ...
                                                  "Visible",              "off");
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app, tabIndex)
            persistent customizationStatus
            if isempty(customizationStatus)
                customizationStatus = [false, false];
            end

            switch tabIndex
                case 0 % STARTUP
                    if app.isDocked
                        app.progressDialog = app.mainApp.progressDialog;
                    else
                        sendEventToHTMLSource(app.jsBackDoor, 'startup', app.mainApp.executionMode);
                        app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);                        
                    end
                    customizationStatus = [false, false];

                otherwise
                    if customizationStatus(tabIndex)
                        return
                    end

                    customizationStatus(tabIndex) = true;
                    switch tabIndex
                        case 1
                            appName = class(app);

                            % Grid botões "dock":
                            if app.isDocked
                                elToModify = {app.DockModule};
                                elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
                                if ~isempty(elDataTag)                                    
                                    sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                                        struct('appName', appName, 'dataTag', elDataTag{1}, 'style', struct('transition', 'opacity 2s ease', 'opacity', '0.5')) ...
                                    });
                                end
                            end

                            % Outros elementos:
                            elToModify = {app.AxesToolbar, app.TreePoints};
                            elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
                            if ~isempty(elDataTag)
                                sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                                    struct('appName', appName, 'dataTag', elDataTag{1}, 'styleImportant', struct('borderTopLeftRadius', '0', 'borderTopRightRadius', '0')), ...
                                    struct('appName', appName, 'dataTag', elDataTag{2}, 'listener', struct('componentName', 'auxApp.winExternalRequest.TreePoints', 'keyEvents', {{'Delete', 'Backspace'}})) ...
                                });
                            end

                        case 2
                            context = 'ExternalRequest';

                            if isempty(app.projectData.modules.(context).ui.system) && ~isequal(app.projectData.modules.(context).ui.system, app.mainApp.General.Report.system)
                                app.projectData.modules.(context).ui.system = app.mainApp.General.Report.system;
                            end
                            
                            if isempty(app.projectData.modules.(context).ui.unit)   && ~isequal(app.projectData.modules.(context).ui.unit,   app.mainApp.General.Report.unit)
                                app.projectData.modules.(context).ui.unit   = app.mainApp.General.Report.unit;
                            end

                            if ~isdeployed()
                                app.reportSystem.Items = {'eFiscaliza', 'eFiscaliza TS', 'eFiscaliza HM', 'eFiscaliza DS'};
                            end
                            app.reportSystem.Value     = app.projectData.modules.(context).ui.system;

                            set(app.reportUnit, 'Items', app.mainApp.General.eFiscaliza.defaultValues.unit, ...
                                                'Value', app.projectData.modules.(context).ui.unit)
                            app.reportIssue.Value     = app.projectData.modules.(context).ui.issue;
                            app.reportModelName.Items = app.projectData.modules.(context).ui.templates;
                    end
            end
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        % INICIALIZAÇÃO
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)
            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                delete(app.timerObj)

                jsBackDoor_Initialization(app)
            end
        end

        %-----------------------------------------------------------------%
        function startup_Controller(app)
            drawnow

            jsBackDoor_Customizations(app, 0)
            jsBackDoor_Customizations(app, 1)

            % Define tamanho mínimo do app (não aplicável à versão webapp).
            if ~strcmp(app.mainApp.executionMode, 'webApp') && ~app.isDocked
                appUtil.winMinSize(app.UIFigure, class.Constants.windowMinSize)
            end

            app.progressDialog.Visible = 'visible';

            startup_GUIComponents(app)

            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            context = 'ExternalRequest';

            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.dockModule_Undock.Enable = 1;
            end

            if app.mainApp.General.(context).FieldValue ~= 14
                app.UITable.ColumnName{4} = sprintf('Qtd.|> %.0f V/m', app.mainApp.General.(context).FieldValue);
            end

            [app.UIAxes, app.restoreView] = plot.axesCreationController(app.plotPanel, app.mainApp.General);

            TreeFileLocationBuilding(app)
            if ~isempty(app.measData)
                Analysis(app)
            end
            
            app.tool_TableVisibility.UserData = 1;

            % Especificidades do auxApp.winExternalRequest em relação ao
            % auxApp.winMonitoringPlan:
            TreePointsBuilding(app)
            app.NewPointType.Items = [{''}; app.mainApp.General.(context).TypeOfLocation];
            layout_newPointPanel(app, 'off')
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function Analysis(app)
            app.progressDialog.Visible = 'visible';

            % Identifica arquivos selecionados pelo usuário, atualizando a
            % tabela de referência para o plot (app.measTable) e a relação
            % de localidades selecionadas na GUI (em app.projectData).
            [idxFile, selectedFileLocations] = FileIndex(app);
            app.measTable = createMeasTable(app.measData(idxFile));

            updateSelectedListOfLocations(...
                app.projectData, ...
                selectedFileLocations, ...
                'ExternalRequest' ...
            )

            initialSelection = updateTable(app);
            layout_TableStyle(app)

            % Atualiza outros elementos da GUI, inclusive painel com quantitativo 
            % de estações.
            updateToolbar(app)            

            % Atualiza plot.
            plot_MeasuresAndPoints(app)

            if ~isempty(initialSelection)
                [~, idxRow] = ismember(initialSelection, app.UITable.Data.ID);
                if idxRow
                    app.UITable.Selection = idxRow;
                    UITableSelectionChanged(app, struct('PreviousSelection', [], 'Selection', idxRow))
                end
            end

            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function [idxFile, selectedFileLocations] = FileIndex(app)
            if ~isempty(app.TreeFileLocations.SelectedNodes)
                selectedFileLocations = {app.TreeFileLocations.SelectedNodes.Text};
                idxFile = find(ismember({app.measData.Location}, selectedFileLocations));
            else
                selectedFileLocations = {};
                idxFile = [];
            end
        end

        %-----------------------------------------------------------------%
        function initialSelection = updateTable(app)
            initialSelection = [];
            if ~isempty(app.UITable.Selection)
                initialSelection = app.UITable.Data.ID{app.UITable.Selection};
            end

            table2Render = app.projectData.modules.ExternalRequest.pointsTable(:, {'ID',                    ...
                                                                                   'Description',           ...                                                                                    
                                                                                   'numberOfMeasures',      ...
                                                                                   'numberOfRiskMeasures',  ...
                                                                                   'minDistanceForMeasure', ...
                                                                                   'minFieldValue',         ...
                                                                                   'meanFieldValue',        ...
                                                                                   'maxFieldValue',         ...
                                                                                   'Justificativa'});
            set(app.UITable, 'Data', table2Render, 'Selection', [])
        end

        %-----------------------------------------------------------------%
        function updateToolbar(app)
            context = 'ExternalRequest';

            measDataNonEmpty                = ~isempty(app.measData);
            measTableNonEmpty               = ~isempty(app.measTable);
            pointsTableNonEmpty             = ~isempty(app.projectData.modules.(context).pointsTable);
            reportModelSelected             = ~isempty(app.reportModelName.Value);
            reportFinalVersionGenerated     = ~isempty(app.projectData.modules.(context).generatedFiles.lastHTMLDocFullPath);
            
            app.tool_ExportFiles.Enable     = pointsTableNonEmpty & measDataNonEmpty;
            app.tool_GenerateReport.Enable  = pointsTableNonEmpty & measDataNonEmpty & reportModelSelected;
            app.tool_UploadFinalFile.Enable = reportFinalVersionGenerated;

            app.tool_peakLabel.Visible      = measTableNonEmpty;
            app.tool_peakIcon.Enable        = measTableNonEmpty;

            if measTableNonEmpty
                [~, maxIndex] = max(app.measTable.FieldValue);

                app.tool_peakLabel.Text    = sprintf('%.2f V/m\n(%.6f, %.6f)', app.measTable.FieldValue(maxIndex), ...
                                                                               app.measTable.Latitude(maxIndex),   ...
                                                                               app.measTable.Longitude(maxIndex));
                app.tool_peakIcon.UserData = struct('idxMax',    maxIndex,                         ...
                                                    'Latitude',  app.measTable.Latitude(maxIndex), ...
                                                    'Longitude', app.measTable.Longitude(maxIndex));
            end
        end

        %-----------------------------------------------------------------%
        function plot_RestartAxes(app)
            cla(app.UIAxes)
            geolimits(app.UIAxes, 'auto')
            app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');
        end

        %-----------------------------------------------------------------%
        function plot_MeasuresAndPoints(app)
            % prePlot
            plot_RestartAxes(app)

            % Measures
            if ~isempty(app.measTable)
                plot.draw.Measures(app.UIAxes, app.measTable, app.mainApp.General.ExternalRequest.FieldValue, app.mainApp.General);

                % Abaixo estabelece como limites do eixo os limites atuais,
                % configurados automaticamente pelo MATLAB. Ao fazer isso,
                % contudo, esses limites serão orientados às medidas e não às
                % estações.
                plot_AxesDefaultLimits(app, 'measures')
            end

            % Stations/Points
            if ~isempty(app.UITable.Data)
                refPointsTable = app.projectData.modules.ExternalRequest.pointsTable;

                plot.draw.Points(app.UIAxes, refPointsTable, 'Pontos críticos', app.mainApp.General)
            end
            plot_AxesDefaultLimits(app, 'stations/points')
        end

        %-----------------------------------------------------------------%
        function plot_SelectedPoint(app)
            delete(findobj(app.UIAxes.Children, 'Tag', 'SelectedPoint'))

            if ~isempty(app.UITable.Selection)
                idxSelectedPoint   = app.UITable.Selection;
                selectedPointTable = app.projectData.modules.ExternalRequest.pointsTable(idxSelectedPoint, :);

                plot.draw.SelectedPoint(app.UIAxes, selectedPointTable, app.mainApp.General, class(app))
            end
        end

        %-----------------------------------------------------------------%
        function plot_AxesDefaultLimits(app, zoomOrientation)
            arguments
                app
                zoomOrientation {mustBeMember(zoomOrientation, {'stations/points', 'measures'})}
            end

            if strcmp(app.mainApp.General.Plot.GeographicAxes.ZoomOrientation, zoomOrientation) || (isempty(app.measTable) && strcmp(app.mainApp.General.Plot.GeographicAxes.ZoomOrientation, 'measures'))
                geolimits(app.UIAxes, app.UIAxes.LatitudeLimits, app.UIAxes.LongitudeLimits)
                app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');
            end
        end

        %-----------------------------------------------------------------%
        function [idxMissingInfo, idxRiskMeasures] = layout_searchUnexpectedTableValues(app)
            idxMissingInfo   = [];
            idxRiskMeasures  = [];

            if ~isempty(app.UITable.Data)
                idxMissingInfo  = find(((app.projectData.modules.ExternalRequest.pointsTable.numberOfMeasures == 0) & (app.projectData.modules.ExternalRequest.pointsTable.("Justificativa") == "-1")));
                idxRiskMeasures = find(app.projectData.modules.ExternalRequest.pointsTable.numberOfRiskMeasures > 0);
            end
        end

        %-----------------------------------------------------------------%
        function layout_TableStyle(app)
            removeStyle(app.UITable)

            tableDataNonEmpty = ~isempty(app.UITable.Data);
            if tableDataNonEmpty
                % Identifica pontos que NÃO tiveram medições no seu entorno. 
                % Ou pontos que apresentaram medições com níveis acima de 14 V/m.
                [idxMissingInfo, idxRiskMeasures] = layout_searchUnexpectedTableValues(app);

                if ~isempty(idxMissingInfo)
                    columnIndex1 = find(ismember(app.UITable.Data.Properties.VariableNames, 'Justificativa'));
                    s1 = uistyle('BackgroundColor', '#c80b0f', 'FontColor', 'white');                
                    addStyle(app.UITable, s1, "cell", [idxMissingInfo, repmat(columnIndex1, numel(idxMissingInfo), 1)])
                end

                if ~isempty(idxRiskMeasures)
                    columnIndex2 = find(ismember(app.UITable.Data.Properties.VariableNames, 'numberOfRiskMeasures'));

                    s2 = uistyle('BackgroundColor', '#c80b0f', 'FontColor', 'white');
                    addStyle(app.UITable, s2, "cell", [idxRiskMeasures, repmat(columnIndex2, numel(idxRiskMeasures), 1)])
                end
            end
        end

        %-----------------------------------------------------------------%
        function TreeFileLocationBuilding(app)
            if ~isempty(app.TreeFileLocations.Children)
                delete(app.TreeFileLocations.Children)
            end

            locationGroups = unique({app.measData.Location});
            selectedNodes = [];
            for ii = 1:numel(locationGroups)
                treeNode = uitreenode(app.TreeFileLocations, 'Text', locationGroups{ii});

                if strcmp(locationGroups{ii}, app.projectData.modules.ExternalRequest.ui.selectedGroup)
                    selectedNodes = [selectedNodes; treeNode];
                end
            end

            measDataNonEmpty = ~isempty(app.measData);
            if measDataNonEmpty
                if isempty(selectedNodes)
                    selectedNodes = app.TreeFileLocations.Children(1);
                elseif ~isscalar(selectedNodes)
                    selectedNodes = selectedNodes(1);
                end
                app.TreeFileLocations.SelectedNodes = selectedNodes;

            else
                app.measTable = [];

                plot_RestartAxes(app)
                updateTable(app);
            end

            updateToolbar(app)
        end

        %-----------------------------------------------------------------%
        function TreePointsBuilding(app)
            if ~isempty(app.TreePoints.Children)
                delete(app.TreePoints.Children)
            end

            for ii = 1:height(app.projectData.modules.ExternalRequest.pointsTable)
                uitreenode(app.TreePoints, 'Text', app.projectData.modules.ExternalRequest.pointsTable.ID{ii}, 'NodeData', ii, 'ContextMenu', app.ContextMenu);
            end
        end

        %-----------------------------------------------------------------%
        function layout_newPointPanel(app, editionStatus)
            arguments
                app 
                editionStatus char {mustBeMember(editionStatus, {'on', 'off'})}
            end            

            switch editionStatus
                case 'on'
                    set(app.AddNewPointMode, 'ImageSource', 'addFiles_32Filled.png', 'Tooltip', 'Desabilita painel de inclusão de ponto', 'UserData', true)
                    
                    app.Control.RowHeight{4} = 170;
                    app.Control.ColumnWidth(end-1:end) = {18, 18};
                    app.AddNewPointConfirm.Enable = 1;
                    app.AddNewPointCancel.Enable  = 1;

                case 'off'
                    set(app.AddNewPointMode, 'ImageSource', 'addFiles_32.png',       'Tooltip', 'Habilita painel de inclusão de ponto',   'UserData', false)

                    app.Control.RowHeight{4} = 0;
                    app.Control.ColumnWidth(end-1:end) = {0,0};
                    app.AddNewPointConfirm.Enable = 0;
                    app.AddNewPointCancel.Enable  = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            app.mainApp     = mainApp;
            app.projectData = mainApp.projectData;
            app.measData    = mainApp.measData;

            if app.isDocked
                app.GridLayout.Padding(4)  = 30;
                app.DockModule.Visible = 1;
                app.jsBackDoor = mainApp.jsBackDoor;
                startup_Controller(app)
            else
                appUtil.winPosition(app.UIFigure)
                startup_timerCreation(app)
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn')
            delete(app)
            
        end

        % Image clicked function: dockModule_Close, dockModule_Undock
        function DockModuleGroup_ButtonPushed(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.dockModule_Undock
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;
                    
                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];
                    
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.dockModule_Close
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Callback function
        function Toolbar_InteractionImageClicked(app, event)
            
            switch event.Source
                case app.tool_ControlPanelVisibility
                    if app.TabGroup.Visible
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowRight_32.png';
                        app.TabGroup.Visible = 0;
                        app.Document.Layout.Column = [2 5];
                    else
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
                        app.TabGroup.Visible = 1;
                        app.Document.Layout.Column = [4 5];
                    end

                case app.tool_TableVisibility
                    app.tool_TableVisibility.UserData = mod(app.tool_TableVisibility.UserData+1, 3);

                    switch app.tool_TableVisibility.UserData
                        case 0
                            app.UITable.Visible = 0;
                            app.Document.RowHeight = {24,'1x', 0, 0};
                        case 1
                            app.UITable.Visible = 1;
                            app.Document.RowHeight = {24, '1x', 10, 186};
                        case 2
                            app.UITable.Visible = 1;
                            app.Document.RowHeight = {0, 0, 0, '1x'};
                    end

                case app.tool_peakIcon
                    if ~isempty(app.tool_peakIcon.UserData)
                        ReferenceDistance_km = 1;
                        plot.zoom(app.UIAxes, app.tool_peakIcon.UserData.Latitude, app.tool_peakIcon.UserData.Longitude, ReferenceDistance_km)
                        plot.datatip.Create(app.UIAxes, 'Measures', app.tool_peakIcon.UserData.idxMax)
                    end
            end

        end

        % Callback function
        function Toolbar_ExportTableAsExcelSheet(app, event)
            
            context = 'ExternalRequest';
            indexes = FileIndex(app);

            pointsTable = app.projectData.modules.(context).pointsTable;
            if isempty(pointsTable)
                warningMessages = 'Funcionalidade aplicável apenas quando há ao menos um ponto crítico';
                appUtil.modalWindow(app.UIFigure, 'warning', warningMessages);
                return
            end

            if ~isempty(indexes)
                % <VALIDAÇÕES>
                if numel(indexes) < numel(app.measData)
                    initialQuestion  = 'Deseja exportar arquivos de análise preliminar (.xlsx / .kml) que contemplem informações de TODAS as localidades de agrupamento, ou apenas da SELECIONADA?';
                    initialSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', initialQuestion, {'Todas', 'Selecionada', 'Cancelar'}, 1, 3);

                    switch initialSelection
                        case 'Cancelar'
                            return
                        case 'Todas'
                            indexes = 1:numel(app.measData);
                    end
                end

                if ~isempty(layout_searchUnexpectedTableValues(app))
                    warningMessages = ['Há registro de pontos críticos localizados na(s) localidade(s) sob análise para os quais '     ...
                                       'não foram identificadas medidas no entorno. Nesse caso específico, deve-se preencher ' ...
                                       'o campo "Justificativa" e anotar os registros, caso aplicável.<br><br>Deseja ignorar ' ...
                                       'esse alerta, exportando PRÉVIA da análise?'];
                    userSelection   = appUtil.modalWindow(app.UIFigure, 'uiconfirm', warningMessages, {'Sim', 'Não'}, 2, 2);
                    if userSelection == "Não"
                        return
                    end
                end
                % </VALIDAÇÕES>
    
                % <PROCESSO>
                % (a) Solicita ao usuário nome do arquivo de saída...
                appName       = class.Constants.appName;
                nameFormatMap = {'*.zip', [appName, ' (*.zip)']};                
                defaultName   = appUtil.DefaultFileName(app.mainApp.General.fileFolder.userPath, [appName '_Preview']);
                fileZIP       = appUtil.modalWindow(app.UIFigure, 'uiputfile', '', nameFormatMap, defaultName);
                if isempty(fileZIP)
                    return
                end
    
                d = appUtil.modalWindow(app.UIFigure, 'progressdlg', 'Em andamento a criação do arquivo de medidas no formato ".xlsx".');
    
                savedFiles   = {};
                errorFiles   = {};
    
                % (b) Gera a tabela global de medidas (englobando todas as localidades 
                %     de agrupamento).
                measTableGlobal    = createMeasTable(app.measData(indexes));

                % (c) Arquivo no formato .XLSX
                fileName_XLSX = fullfile(app.mainApp.General.fileFolder.tempPath, 'Demanda externa (Preview).xlsx');
                [status, msgError] = fileWriter.ExternalRequest(fileName_XLSX, pointsTable, timetable2table(measTableGlobal), app.mainApp.General.(context).FieldValue, app.mainApp.General.(context).Export.XLSX);
                if status
                    savedFiles{end+1} = fileName_XLSX;
                else
                    errorFiles{end+1} = msgError;
                end
        
                % (d) Arquivos no formato .KML: "Measures" e "Route" 
                if app.mainApp.General.(context).Export.KML
                    d.Message = textFormatGUI.HTMLParagraph('Em andamento a criação dos arquivos de medidas e rotas no formato ".kml".');

                    groupLocations = unique({app.measData(indexes).Location});

                    for ii = 1:numel(groupLocations)
                        groupLocation = groupLocations{ii};
                        [~, groupLocationIndex] = ismember(groupLocation, {app.TreeFileLocations.Children.Text});

                        if ~isequal(app.TreeFileLocations.SelectedNodes, app.TreeFileLocations.Children(groupLocationIndex))
                            app.TreeFileLocations.SelectedNodes = app.TreeFileLocations.Children(groupLocationIndex);
                            TreeFileLocationsSelectionChanged(app, struct('SelectedNodes', app.TreeFileLocations.Children(groupLocationIndex)))
                        end

                        groupLocationText    = replace(app.TreeFileLocations.SelectedNodes.Text, '/', '-');
                        groupLocationIndexes = FileIndex(app);
                        groupLocationMeasTable = createMeasTable(app.measData(groupLocationIndexes));

                        % MEDIDAS
                        hMeasPlot = findobj(app.UIAxes.Children, 'Tag', 'Measures');                        
                        KML1File  = fullfile(app.mainApp.General.fileFolder.tempPath, sprintf('%s (Measures).kml', groupLocationText));
                        [status1, msgError1] = fileWriter.KML(KML1File, 'Measures', timetable2table(groupLocationMeasTable), hMeasPlot);
                        if status1
                            savedFiles{end+1} = KML1File;
                        else
                            errorFiles{end+1} = msgError1;
                        end

                        % ROTA
                        KML2File = fullfile(app.mainApp.General.fileFolder.tempPath, sprintf('%s (Route).kml', groupLocationText));
                        [status2, msgError2] = fileWriter.KML(KML2File, 'Route', timetable2table(groupLocationMeasTable));
                        if status2
                            savedFiles{end+1} = KML2File;
                        else
                            errorFiles{end+1} = msgError2;
                        end
                    end
                end

                % (e) Arquivo no formato .ZIP
                if ~isempty(savedFiles)
                    zip(fileZIP, savedFiles)
    
                    [~, fileName, fileExt] = fileparts(savedFiles);
                    savedFiles = strcat('•&thinsp;', fileName, fileExt);
                    appUtil.modalWindow(app.UIFigure, 'none', sprintf('Lista de arquivos criados:\n%s', strjoin(savedFiles, '\n')));
                end
    
                if ~isempty(errorFiles)
                    appUtil.modalWindow(app.UIFigure, 'error', strjoin(errorFiles, '\n'));
                end

                delete(d)
            end

        end

        % Callback function
        function Toolbar_GenerateReportImageClicked(app, event)
            
            context = 'ExternalRequest';
            indexes = FileIndex(app);

            if ~isempty(indexes)
                % <VALIDAÇÕES>
                if numel(indexes) < numel(app.measData)
                    initialQuestion  = 'Deseja gerar relatório que contemple informações de TODAS as localidades de agrupamento, ou apenas da SELECIONADA?';
                    initialSelection = appUtil.modalWindow(app.UIFigure, 'uiconfirm', initialQuestion, {'Todas', 'Selecionada', 'Cancelar'}, 1, 3);

                    switch initialSelection
                        case 'Cancelar'
                            return
                        case 'Todas'
                            indexes = 1:numel(app.measData);
                    end
                end

                warningMessages = {};
                if ~report_checkEFiscalizaIssueId(app.mainApp, app.projectData.modules.(context).ui.issue)
                    warningMessages{end+1} = sprintf('O número da inspeção "%.0f" é inválido.', app.projectData.modules.(context).ui.issue);
                end
                
                if ~isempty(layout_searchUnexpectedTableValues(app))
                    warningMessages{end+1} = ['Há registro de pontos críticos localizados na(s) localidade(s) sob análise para os quais '     ...
                                              'não foram identificadas medidas no entorno. Nesse caso específico, deve-se preencher ' ...
                                              'o campo "Justificativa" e anotar os registros, caso aplicável.'];
                end

                if ~isempty(warningMessages)
                    warningMessages = strjoin(warningMessages, '<br><br>');

                    switch app.reportVersion.Value
                        case 'Definitiva'
                            warningMessages = [warningMessages, '<br><br>Isso impossibilita a geração da versão DEFINITIVA do relatório.'];
                            appUtil.modalWindow(app.UIFigure, "warning", warningMessages);
                            return

                        otherwise % 'Preliminar'
                            warningMessages = [warningMessages, '<br><br>Deseja ignorar esse alerta, gerando a versão PRÉVIA do relatório?'];
                            userSelection   = appUtil.modalWindow(app.UIFigure, 'uiconfirm', warningMessages, {'Sim', 'Não'}, 2, 2);
                            if userSelection == "Não"
                                return
                            end
                    end
                end
                % </VALIDAÇÕES>

                % <PROCESSO>
                app.progressDialog.Visible = 'visible';

                try
                    reportSettings = struct('system',        app.reportSystem.Value, ...
                                            'unit',          app.reportUnit.Value, ...
                                            'issue',         app.reportIssue.Value, ...
                                            'model',         app.reportModelName.Value, ...
                                            'reportVersion', app.reportVersion.Value);
                    reportLibConnection.Controller.Run(app, app.projectData, app.measData(indexes), reportSettings, app.mainApp.General)
                catch ME
                    appUtil.modalWindow(app.UIFigure, 'error', getReport(ME));
                end

                updateToolbar(app)

                app.progressDialog.Visible = 'hidden';
                % </PROCESSO>
            end

        end

        % Callback function
        function Toolbar_UploadFinalFileImageClicked(app, event)
            
            % <VALIDAÇÕES>
            context = 'ExternalRequest';
            lastHTMLDocFullPath = getGeneratedDocumentFileName(app.projectData, '.html', context);

            msg = '';
            if isempty(lastHTMLDocFullPath)
                msg = 'A versão definitiva do relatório ainda não foi gerada.';
            elseif ~isfile(lastHTMLDocFullPath)
                msg = sprintf('O arquivo "%s" não foi encontrado.', lastHTMLDocFullPath);
            elseif ~isfolder(app.mainApp.General.fileFolder.DataHub_POST)
                msg = 'Pendente mapear pasta do Sharepoint';
            elseif ~report_checkEFiscalizaIssueId(app.mainApp, app.projectData.modules.(context).ui.issue)
                msg = sprintf('O número da inspeção "%.0f" é inválido.', app.projectData.modules.(context).ui.issue);
            elseif isempty(app.projectData.modules.(context).ui.system)
                msg = 'Ambiente do eFiscaliza precisa ser selecionado.';
            elseif isempty(app.projectData.modules.(context).ui.unit)
                msg = 'Unidade geradora do documento precisa ser selecionada.';
            end

            if ~isempty(msg)
                appUtil.modalWindow(app.UIFigure, 'warning', msg);
                return
            end
            % </VALIDAÇÕES>

            % <PROCESSO>
            if isempty(app.mainApp.eFiscalizaObj)
                dialogBox    = struct('id', 'login',    'label', 'Usuário: ', 'type', 'text');
                dialogBox(2) = struct('id', 'password', 'label', 'Senha: ',   'type', 'password');
                sendEventToHTMLSource(app.jsBackDoor, 'customForm', struct('UUID', 'eFiscalizaSignInPage', 'Fields', dialogBox, 'Context', context))
            else
                report_uploadInfoController(app.mainApp, [], 'uploadDocument', context)
            end
            % </PROCESSO>

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)

            [~, tabIndex] = ismember(app.TabGroup.SelectedTab, app.TabGroup.Children);
            jsBackDoor_Customizations(app, tabIndex)

        end

        % Callback function
        function AxesToolbarImageClicked(app, event)
            
            switch event.Source
                case app.axesTool_RestoreView
                    geolimits(app.UIAxes, app.restoreView(1).xLim, app.restoreView(1).yLim)

                case app.axesTool_RegionZoom
                    plot.axes.Interactivity.GeographicRegionZoomInteraction(app.UIAxes, app.axesTool_RegionZoom)
            end

        end

        % Callback function
        function TreePointsSelectionChanged(app, event)
            
            idxPoint = [];
            if ~isempty(app.TreePoints.SelectedNodes)
                idxPoint = app.TreePoints.SelectedNodes.NodeData;
            end

            % Nessa operação, o foco sai de app.TreePoints e vai pra app.UITable.
            previousSelection = app.UITable.Selection;
            app.UITable.Selection = idxPoint;
            drawnow            

            UITableSelectionChanged(app, struct('PreviousSelection', previousSelection, 'Selection', idxPoint))            
            focus(app.TreePoints)

        end

        % Callback function
        function TreeFileLocationsSelectionChanged(app, event)
            
            if isempty(event.SelectedNodes)
                app.TreeFileLocations.SelectedNodes = event.PreviousSelectedNodes;
                return
            end

            Analysis(app)

        end

        % Callback function
        function UITableCellEdit(app, event)
            
            if ~ismember(event.EditData, app.mainApp.General.ExternalRequest.NoMeasureReasons)
                app.UITable.Data.("Justificativa") = app.projectData.modules.ExternalRequest.pointsTable.("Justificativa");
                return
            end

            idxPoint = event.Indices(1);
            app.projectData.modules.ExternalRequest.pointsTable.("Justificativa")(idxPoint) = event.NewData;
            
            layout_TableStyle(app)

        end

        % Callback function
        function UITableSelectionChanged(app, event)
            
            if exist('event', 'var') && ~isempty(event.PreviousSelection) && isequal(event.Selection, event.PreviousSelection)
                return
            end

            if isempty(event.Selection)
                app.UITable.ContextMenu = [];
                app.TreePoints.SelectedNodes = [];
            else
                if isempty(app.UITable.ContextMenu)
                    app.UITable.ContextMenu = app.ContextMenu;
                end
                app.TreePoints.SelectedNodes = app.TreePoints.Children(event.Selection);
            end

            plot_SelectedPoint(app)

        end

        % Callback function
        function NewPointTypeValueChanged(app, event)
            
            switch app.NewPointType.Value
                case 'Estação'
                    app.NewPointStation.Enable = 1;
                    set(app.NewPointLatitude,  'Value', -1, 'Enable', 0)
                    set(app.NewPointLongitude, 'Value', -1, 'Enable', 0)

                otherwise
                    set(app.NewPointStation, 'Value', -1, 'Enable', 0)
                    app.NewPointLatitude.Enable  = 1;
                    app.NewPointLongitude.Enable = 1;
            end

        end

        % Callback function
        function NewPointStationValueChanged(app, event)
            
            idxRFDataHub = find(app.mainApp.rfDataHub.Station == app.NewPointStation.Value, 1);

            if ~isempty(idxRFDataHub)
                latStation  = round(app.mainApp.rfDataHub.Latitude(idxRFDataHub), 6);
                longStation = round(app.mainApp.rfDataHub.Longitude(idxRFDataHub), 6);

                set(app.NewPointLatitude,  'Value', latStation,  'Enable', 1)
                set(app.NewPointLongitude, 'Value', longStation, 'Enable', 1)

            else
                set(app.NewPointLatitude,  'Value', -1, 'Enable', 1)
                set(app.NewPointLongitude, 'Value', -1, 'Enable', 1)
            end

            focus(app.NewPointLatitude);

        end

        % Callback function
        function NewPointLatitudeValueChanged(app, event)
            
            focus(app.NewPointLongitude)
            
        end

        % Callback function
        function AddNewPointEditionModeCallbacks(app, event)
            
            switch event.Source
                case app.AddNewPointMode
                    app.AddNewPointMode.UserData = ~app.AddNewPointMode.UserData;
                    
                    if app.AddNewPointMode.UserData
                        layout_newPointPanel(app, 'on')
                    else
                        layout_newPointPanel(app, 'off')
                    end

                case app.AddNewPointConfirm
                    % VALIDAÇÃO
                    if isempty(app.NewPointType.Value) || ((app.NewPointLatitude.Value == -1) && (app.NewPointLongitude.Value == -1))
                        msgWarning = 'Um novo ponto crítico somente poderá ser incluído se definido o seu tipo e coordenadas geográficas diferentes de (-1, -1).';
                        appUtil.modalWindow(app.UIFigure, 'warning', msgWarning);
                        return
                    end
        
                    % CRIA ID DO NOVO PONTO
                    switch app.NewPointType.Value
                        case 'Estação'
                            ID = num2str(app.NewPointStation.Value);
                        otherwise
                            ID = app.NewPointType.Value;
                    end
                    ID = sprintf('%s @ (%.6f, %.6f)', ID, app.NewPointLatitude.Value, app.NewPointLongitude.Value);
        
                    % VERIFICA SE ESSE ID JÁ TINHA SIDO INCLUÍDO
                    if any(strcmp(app.projectData.modules.ExternalRequest.pointsTable_I.ID, ID))
                        appUtil.modalWindow(app.UIFigure, 'warning', 'Registro já consta na lista de pontos sob análise.');
                        return
                    end

                    columsn2Fill = {'ID', 'Type', 'Station', 'Latitude', 'Longitude', 'Description', 'Justificativa', 'AnalysisFlag'};        
                    app.projectData.modules.ExternalRequest.pointsTable_I(end+1, columsn2Fill) = {ID,                            ...
                                                                                                app.NewPointType.Value,        ...
                                                                                                app.NewPointStation.Value,     ...
                                                                                                app.NewPointLatitude.Value,    ...
                                                                                                app.NewPointLongitude.Value,   ...
                                                                                                app.NewPointDescription.Value, ...
                                                                                                categorical("-1"),             ...
                                                                                                false};
                    app.projectData.modules.ExternalRequest.pointsTable_I = model.projectLib.generateHash(app.projectData.modules.ExternalRequest.pointsTable_I, 'pointsTable');
                    app.projectData.modules.ExternalRequest.pointsTable(end+1, :) = app.projectData.modules.ExternalRequest.pointsTable_I(end, :);
                    updateAnalysis(app.projectData, app.measData, app.mainApp.General, 'ExternalRequest:PointsTableChanged');
        
                    % ATUALIZA ÁRVORE DE PONTOS CRÍTICOS
                    TreePointsBuilding(app)
        
                    % ANÁLISA DOS PONTOS CRÍTICOS, ATUALIZANDO TABELA E PLOT
                    Analysis(app)

                    % DESABILITA MODO DE INCLUSÃO DE PONTO
                    layout_newPointPanel(app, 'off')

                case app.AddNewPointCancel
                    layout_newPointPanel(app, 'off')
            end

        end

        % Menu selected function: DeletePoint
        function DeleteSelectedPoint(app, event)
            
            idxPoint = [];

            if ismember(app.TreePoints, [event.ContextObject, event.ContextObject.Parent])
                if ~isempty(app.TreePoints.SelectedNodes)
                    idxPoint = app.TreePoints.SelectedNodes.NodeData;
                end
            elseif event.ContextObject == app.UITable
                idxPoint = app.UITable.Selection;
            end

            if ~isempty(idxPoint)
                app.projectData.modules.ExternalRequest.pointsTable_I(idxPoint, :) = [];
                app.projectData.modules.ExternalRequest.pointsTable(idxPoint, :)   = [];
                updateAnalysis(app.projectData, app.measData, app.mainApp.General, 'ExternalRequest:PointsTableChanged');

                % ATUALIZA ÁRVORE DE PONTOS CRÍTICOS
                TreePointsBuilding(app)
    
                % ANÁLISA DOS PONTOS CRÍTICOS, ATUALIZANDO TABELA E PLOT
                Analysis(app)
            end

        end

        % Value changed function: reportModelName
        function reportModelNameValueChanged(app, event)
            
            updateToolbar(app)

        end

        % Value changed function: reportIssue, reportSystem, reportUnit
        function reportInfoValueChanged(app, event)
            
            context = 'ExternalRequest';

            switch event.Source
                case app.reportSystem
                    updateUiInfo(app.projectData, context, 'system', app.reportSystem.Value)
                case app.reportUnit
                    updateUiInfo(app.projectData, context, 'unit',   app.reportUnit.Value)
                case app.reportIssue
                    updateUiInfo(app.projectData, context, 'issue',  app.reportIssue.Value)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorRNI';
            app.UIFigure.Icon = 'C:\InovaFiscaliza\SCH\src\resources\Icons\icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Toolbar = uigridlayout(app.GridLayout);
            app.report_leftPanelVisibility = uiimage(app.Toolbar);
            app.report_leftPanelVisibility.ImageSource = 'ArrowLeft_32.png';
            app.report_ReportGeneration = uiimage(app.Toolbar);
            app.report_ReportGeneration.Enable = 'off';
            app.report_ReportGeneration.Tooltip = {'Gera relatório'};
            app.report_ReportGeneration.ImageSource = 'Publish_HTML_16.png';
            app.report_FiscalizaUpdate = uiimage(app.Toolbar);
            app.report_FiscalizaUpdate.Enable = 'off';
            app.report_FiscalizaUpdate.Tooltip = {'Upload relatório'};
            app.report_FiscalizaUpdate.ImageSource = 'Up_24.png';
            app.Image5 = uiimage(app.Toolbar);
            app.Image5.Tooltip = {'Adiciona produto NÃO homologado à lista'};
            app.Image5.ImageSource = 'Forbidden_32Red.png';
            app.report_EditProduct = uiimage(app.Toolbar);
            app.report_EditProduct.Enable = 'off';
            app.report_EditProduct.Tooltip = {'Edita lista de produtos'};
            app.report_EditProduct.ImageSource = 'Variable_edit_16.png';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PRODUTOTab = uitab(app.TabGroup);
            app.PRODUTOTab.Title = 'PRODUTO';
            app.report_Tab1Grid = uigridlayout(app.PRODUTOTab);
            app.report_ProductInfoImage = uiimage(app.report_Tab1Grid);
            app.report_ProductInfoImage.ImageSource = 'warning.svg';
            app.report_ProductInfo = uilabel(app.report_Tab1Grid);
            app.report_ProductInfo.Interpreter = 'html';
            app.report_ProductInfo.Text = '';
            app.report_ProjectLabel_2 = uilabel(app.report_Tab1Grid);
            app.report_ProjectLabel_2.Text = 'PRODUTO SELECIONADO';
            app.PROJETOTab = uitab(app.TabGroup);
            app.PROJETOTab.Title = 'PROJETO';
            app.Tab4Grid = uigridlayout(app.PROJETOTab);
            app.eFiscalizaLabel = uilabel(app.Tab4Grid);
            app.eFiscalizaLabel.Text = 'eFISCALIZA';
            app.eFiscalizaPanel = uipanel(app.Tab4Grid);
            app.eFiscalizaGrid = uigridlayout(app.eFiscalizaPanel);
            app.reportSystemLabel = uilabel(app.eFiscalizaGrid);
            app.reportSystemLabel.Text = 'Sistema:';
            app.reportSystem = uidropdown(app.eFiscalizaGrid);
            app.reportSystem.Items = {'eFiscaliza', 'eFiscaliza TS'};
            app.reportSystem.ValueChangedFcn = createCallbackFcn(app, @reportInfoValueChanged, true);
            app.reportSystem.Value = 'eFiscaliza';
            app.reportUnitLabel = uilabel(app.eFiscalizaGrid);
            app.reportUnitLabel.Text = 'Unidade responsável:';
            app.reportUnit = uidropdown(app.eFiscalizaGrid);
            app.reportUnit.Items = {};
            app.reportUnit.ValueChangedFcn = createCallbackFcn(app, @reportInfoValueChanged, true);
            app.reportUnit.Value = {};
            app.reportIssueLabel = uilabel(app.eFiscalizaGrid);
            app.reportIssueLabel.Text = {'Atividade de inspeção:'; '(# ID)'};
            app.reportIssue = uieditfield(app.eFiscalizaGrid, 'numeric');
            app.reportIssue.Limits = [-1 Inf];
            app.reportIssue.RoundFractionalValues = 'on';
            app.reportIssue.ValueDisplayFormat = '%d';
            app.reportIssue.ValueChangedFcn = createCallbackFcn(app, @reportInfoValueChanged, true);
            app.reportIssue.Value = -1;
            app.reportLabel = uilabel(app.Tab4Grid);
            app.reportLabel.Text = 'RELATÓRIO';
            app.reportPanel = uipanel(app.Tab4Grid);
            app.reportGrid = uigridlayout(app.reportPanel);
            app.reportModelNameLabel = uilabel(app.reportGrid);
            app.reportModelNameLabel.Text = 'Modelo (.json):';
            app.reportModelName = uidropdown(app.reportGrid);
            app.reportModelName.Items = {''};
            app.reportModelName.ValueChangedFcn = createCallbackFcn(app, @reportModelNameValueChanged, true);
            app.reportModelName.Value = '';
            app.reportVersionLabel = uilabel(app.reportGrid);
            app.reportVersionLabel.Text = 'Versão do relatório:';
            app.reportVersion = uidropdown(app.reportGrid);
            app.reportVersion.Items = {'Preliminar', 'Definitiva'};
            app.reportVersion.Value = 'Preliminar';
            app.report_ProjectLabel = uilabel(app.Tab4Grid);
            app.report_ProjectLabel.Text = 'ARQUIVO';
            app.report_ProjectNew = uiimage(app.Tab4Grid);
            app.report_ProjectNew.Tooltip = {'Cria novo projeto'};
            app.report_ProjectNew.ImageSource = 'AddFiles_36.png';
            app.report_ProjectOpen = uiimage(app.Tab4Grid);
            app.report_ProjectOpen.Tooltip = {'Abre projeto'};
            app.report_ProjectOpen.ImageSource = 'OpenFile_36x36.png';
            app.report_ProjectSave = uiimage(app.Tab4Grid);
            app.report_ProjectSave.Tooltip = {'Salva projeto'};
            app.report_ProjectSave.ImageSource = 'SaveFile_36.png';
            app.report_ProjectName = uitextarea(app.Tab4Grid);
            app.report_ProjectName.Editable = 'off';
            app.report_EntityPanelLabel = uilabel(app.Tab4Grid);
            app.report_EntityPanelLabel.Text = 'FISCALIZADA';
            app.report_EntityPanel = uipanel(app.Tab4Grid);
            app.report_EntityGrid = uigridlayout(app.report_EntityPanel);
            app.report_EntityTypeLabel = uilabel(app.report_EntityGrid);
            app.report_EntityTypeLabel.Text = 'Tipo:';
            app.report_EntityType = uidropdown(app.report_EntityGrid);
            app.report_EntityType.Items = {'', 'Importador', 'Fornecedor', 'Usuário'};
            app.report_EntityType.Value = '';
            app.report_EntityIDLabel = uilabel(app.report_EntityGrid);
            app.report_EntityIDLabel.Text = 'CNPJ/CPF:';
            app.report_EntityCheck = uiimage(app.report_EntityGrid);
            app.report_EntityCheck.Enable = 'off';
            app.report_EntityCheck.ImageSource = 'Info_36.png';
            app.report_EntityID = uieditfield(app.report_EntityGrid, 'text');
            app.report_EntityID.Enable = 'off';
            app.report_EntityLabel = uilabel(app.report_EntityGrid);
            app.report_EntityLabel.Text = 'Nome:';
            app.report_Entity = uieditfield(app.report_EntityGrid, 'text');
            app.report_Entity.Enable = 'off';
            app.Document = uigridlayout(app.GridLayout);
            app.report_ViewType = uibuttongroup(app.Document);
            app.report_ViewType.Title = 'LISTA DE PRODUTOS SOB ANÁLISE';
            app.FornecedorUsurioButton = uiradiobutton(app.report_ViewType);
            app.FornecedorUsurioButton.Tag = 'vendorView';
            app.FornecedorUsurioButton.Text = 'Fornecedor/Usuário';
            app.FornecedorUsurioButton.Value = true;
            app.AduanaButton = uiradiobutton(app.report_ViewType);
            app.AduanaButton.Tag = 'customsView';
            app.AduanaButton.Text = 'Aduana';
            app.report_nRows = uilabel(app.Document);
            app.report_nRows.Interpreter = 'html';
            app.report_nRows.Text = '0 <font style="font-size: 9px; margin-right: 2px;">REGISTROS</font>';
            app.report_Table = uitable(app.Document);
            app.report_Table.ColumnName = {'HOMOLOGAÇÃO'; 'TIPO'; 'FABRICANTE'; 'MODELO'; 'RF?'; 'EM USO?'; 'INTERFERÊNCIA?'; 'VALOR|UNITÁRIO (R$)'; 'FONTE|VALOR'; 'QTD.|VENDIDA'; 'QTD.|EM USO'; 'QTD.|ESTOQUE'; 'QTD.|ANUNCIADA'; 'QTD.|LACRADA'; 'QTD.|APREENDIDA'; 'QTD.|RETIDA (RFB)'; 'SITUAÇÃO'; 'INFRAÇÃO'};
            app.report_Table.RowName = {};
            app.report_Table.SelectionType = 'row';
            app.report_Table.ColumnEditable = [false true true true true true true true true true true true true true true true true true];
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.dockModule_Close = uiimage(app.DockModule);
            app.dockModule_Close.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Close.Tag = 'DRIVETEST';
            app.dockModule_Close.Tooltip = {'Fecha módulo'};
            app.dockModule_Close.ImageSource = 'Delete_12SVG_white.svg';
            app.dockModule_Undock = uiimage(app.DockModule);
            app.dockModule_Undock.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Undock.Tag = 'DRIVETEST';
            app.dockModule_Undock.Enable = 'off';
            app.dockModule_Undock.Tooltip = {'Reabre módulo em outra janela'};
            app.dockModule_Undock.ImageSource = 'Undock_18White.png';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.Tag = 'auxApp.winExternalRequest';
            app.EditarMenu = uimenu(app.ContextMenu);
            app.EditarMenu.Enable = 'off';
            app.EditarMenu.Text = 'Editar';
            app.DeletePoint = uimenu(app.ContextMenu);
            app.DeletePoint.MenuSelectedFcn = createCallbackFcn(app, @DeleteSelectedPoint, true);
            app.DeletePoint.Enable = 'off';
            app.DeletePoint.Separator = 'on';
            app.DeletePoint.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winProducts(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end