classdef webSCH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        splashscreenGrid   matlab.ui.container.GridLayout
        splashscreenTitle  matlab.ui.control.Label
        splashscreenGIF    matlab.ui.control.Image
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        General

        RootFolder
        userPath

        timerObj
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_WindowPosition(app)
            fcn.winPosition(app)                
        end


        %-----------------------------------------------------------------%
        function startup_TimerCreation(app)
            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1,              ...
                                 "Period",        .250,           ...
                                 "TimerFcn",      @(~,~)app.startup_TimerFcn);
            start(app.timerObj)
        end


        %-----------------------------------------------------------------%
        function startup_TimerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                createMainComponents(app)
            end
        end


        %-----------------------------------------------------------------%
        function createMainComponents(app)

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % AQUI ENTRA A BAGACEIRA...
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            delete(app.splashscreenGrid)
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_HTMLSource(app)
            app.jsBackDoor.HTMLSource = sprintf('<!DOCTYPE html>\n<html>\n<head>\n<script>\n%s\n</script>\n</head>\n\n<body>\n</body>\n</html>', ...
                                                fileread(fullfile(ccTools.Path, 'css&js', 'compCustomization.js')));
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_compCustomization(app)
            % Implementar depois o retorno do JS >> Matlab, indicando os
            % valores dos parâmetros programados, confirmando, então, se
            % foi realizada a alteração desejada.
            % try
            %     ccTools.compCustomizationV2(app.jsBackDoor, app.UITable, "fontSize", "11px", "paddingTop", "0px")
            % catch
            % end

            % Em relação ao painel de controle, troquei o sistema de visibilidade 
            % de linhas de um Grid por um sistema de abas. Em tese, isso
            % deve tornar a inicialização do webapp mais eficiente porque
            % somente serão renderizadas em tela os componentes da aba
            % selecionada. 
            % Acontece que ao fazer isso, a janela de progresso do MATLAB
            % WEB SERVER não é excluída após a carga do app. Criei o evento
            % para excluí-la.
            sendEventToHTMLSource(app.jsBackDoor, "delProgressDialog");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            try
                % PATH SEARCH
                app.RootFolder = fileparts(mfilename('fullpath'));

                % WARNING MESSAGES
                warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                warning('off', 'MATLAB:structOnObject')
                warning('off', 'MATLAB:class:DestructorError')
                warning('off', 'MATLAB:modes:mode:InvalidPropertySet')
                warning('off', 'MATLAB:table:RowsAddedExistingVars')

                % STARTUP
                app.splashscreenTitle.Text = sprintf('%s<font style="font-size:12px;"> v. %s</font>', class.Constants.appName, class.Constants.appVersion);
                startup_WindowPosition(app)
                startup_TimerCreation(app)

            catch ME
                layoutFcn.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            if ~isdeployed
                delete(timerfindall)
            end

            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'Icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.splashscreenGrid = uigridlayout(app.UIFigure);
            app.splashscreenGIF = uiimage(app.splashscreenGrid);
            app.splashscreenGIF.Enable = 'off';
            app.splashscreenGIF.ImageSource = fullfile(pathToMLAPP, 'Icons', 'SplashScreen.gif');
            app.splashscreenTitle = uilabel(app.splashscreenGrid);
            app.splashscreenTitle.Interpreter = 'html';
            app.splashscreenTitle.Text = 'SCH<font style="font-size:12px;"> v. 0.01</font>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = webSCH(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end